classdef AmbiSettings < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        AmbisonicsPanel       matlab.ui.container.Panel
        dBLabel               matlab.ui.control.Label
        MaxGainEditField      matlab.ui.control.NumericEditField
        Label                 matlab.ui.control.Label
        FilterOrderEditField  matlab.ui.control.NumericEditField
        AscertainButton       matlab.ui.control.Button
        dBLabel_2             matlab.ui.control.Label
        MaxOrderEditField     matlab.ui.control.NumericEditField
        CancelButton          matlab.ui.control.Button
    end

    
    properties (Access = private)
        CallingApp
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, maxOrder, initAmbi)
            app.CallingApp = mainapp;
            app.MaxOrderEditField.Value = maxOrder;
            app.MaxGainEditField.Value = initAmbi.MaxGain;
            app.FilterOrderEditField.Value = initAmbi.FilterOrder;
        end

        % Button pushed function: AscertainButton
        function AscertainButtonPushed(app, event)
            d = uiprogressdlg(app.UIFigure, 'Title', '正在进行初始化设置', ...
                    'Message', '请您耐心等待', 'Indeterminate', 'on');

            app.CallingApp.maxOrder = app.MaxOrderEditField.Value;
            app.CallingApp.Ambi_n_Spinner.Limits = [0 app.CallingApp.maxOrder];
            app.CallingApp.initAmbi.MaxGain = app.MaxGainEditField.Value;
            app.CallingApp.initAmbi.FilterOrder = app.FilterOrderEditField.Value;

            app.CallingApp.AmbiSettingButton.Enable = 'on';

%             app.CallingApp.AmbiSourceIDSpinner.Enable = 'off';
            app.CallingApp.AmbiSourceIDDropDown.Enable = 'off';
            app.CallingApp.Ambi_n_Spinner.Enable = 'off';
            app.CallingApp.Ambi_m_Spinner.Enable = 'off';
            app.CallingApp.AmbiPlotButton.Enable = 'off';
            app.CallingApp.AmbiExportButton.Enable = 'off';
            cla(app.CallingApp.AmbiPlotUIAxes);

%             app.CallingApp.PowermapSourceIDSpinner.Enable = 'off';
            app.CallingApp.PowermapSourceIDDropDown.Enable = 'off';
            app.CallingApp.PowermapAziSlider.Enable = 'off';
            app.CallingApp.PowermapEleSlider.Enable = 'off';
            app.CallingApp.PowermapPlotButton.Enable = 'off';
            app.CallingApp.PowermapExportButton.Enable = 'off';
            cla(app.CallingApp.PowermapPlotUIAxes);

%             app.CallingApp.IRISSourceIDSpinner.Enable = 'off';
            app.CallingApp.IRISSourceIDDropDown.Enable = 'off';
            app.CallingApp.IRISAziSlider.Enable = 'off';
            app.CallingApp.IRISEleSlider.Enable = 'off';
            app.CallingApp.IRISPlotButton.Enable = 'off';
            app.CallingApp.IRISExportButton.Enable = 'off';
            cla(app.CallingApp.IRISPlotUIAxes1);

%             app.CallingApp.SourceLocSourceIDSpinner.Enable = 'off';
            app.CallingApp.SourceLocSourceIDDropDown.Enable = 'off';
            app.CallingApp.SourceLocAziSlider.Enable = 'off';
            app.CallingApp.SourceLocEleSlider.Enable = 'off';
            app.CallingApp.SourceLocPlotButton.Enable = 'off';
            app.CallingApp.SourceLocExportButton.Enable = 'off';
            cla(app.CallingApp.SourceLocPlotUIAxes);

            pause(.5)
            close(d);

            uialert(app.CallingApp.UIFigure, '已完成 < 高阶Ambisonics > 初始化设置！', '成功', 'Icon', 'success');
            
            delete(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.CallingApp.AmbiSettingButton.Enable = 'on';
            delete(app)
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.CallingApp.AmbiSettingButton.Enable = 'on';
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.AmbisonicsPanel = uipanel(app.UIFigure);
            app.AmbisonicsPanel.Title = '高阶 Ambisonics 初始化设置';
            app.dBLabel = uilabel(app.AmbisonicsPanel);
            app.dBLabel.Text = '最大增益（dB）：';
            app.MaxGainEditField = uieditfield(app.AmbisonicsPanel, 'numeric');
            app.MaxGainEditField.Value = 20;
            app.Label = uilabel(app.AmbisonicsPanel);
            app.Label.Text = '滤波器阶数：';
            app.FilterOrderEditField = uieditfield(app.AmbisonicsPanel, 'numeric');
            app.FilterOrderEditField.Value = 256;
            app.AscertainButton = uibutton(app.AmbisonicsPanel, 'push');
            app.AscertainButton.ButtonPushedFcn = createCallbackFcn(app, @AscertainButtonPushed, true);
            app.AscertainButton.Text = {'确定'; ''};
            app.dBLabel_2 = uilabel(app.AmbisonicsPanel);
            app.dBLabel_2.Text = '球谐分解最高阶数：';
            app.MaxOrderEditField = uieditfield(app.AmbisonicsPanel, 'numeric');
            app.MaxOrderEditField.Value = 8;
            app.CancelButton = uibutton(app.AmbisonicsPanel, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = {'取消'; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AmbiSettings(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end