classdef IRISSettings < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        IRISSettingsPanel              matlab.ui.container.Panel
        AscertainButton                matlab.ui.control.Button
        LPFilterSettingPanel           matlab.ui.container.Panel
        SwitchLabel                    matlab.ui.control.Label
        Switch                         matlab.ui.control.Switch
        dBLabel_7                      matlab.ui.control.Label
        CutoffFreqEditField            matlab.ui.control.NumericEditField
        dBLabel_8                      matlab.ui.control.Label
        FilterOrderEditField           matlab.ui.control.NumericEditField
        IRISPlotSettingPanel           matlab.ui.container.Panel
        dBLabel_2                      matlab.ui.control.Label
        SegmentTimeEditField           matlab.ui.control.NumericEditField
        dBLabel_3                      matlab.ui.control.Label
        OffSetEditField                matlab.ui.control.NumericEditField
        dBLabel_4                      matlab.ui.control.Label
        LPFilterFcEditField            matlab.ui.control.NumericEditField
        dBLabel_5                      matlab.ui.control.Label
        tMaxEditField                  matlab.ui.control.NumericEditField
        dBLabel_6                      matlab.ui.control.Label
        DirectSoundThresholdEditField  matlab.ui.control.NumericEditField
        CancelButton                   matlab.ui.control.Button
    end

    
    properties (Access = private)
        CallingApp
        lowPassFilterParams = struct;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, initIRIS, CONF)
            app.CallingApp = mainapp;
            
            app.lowPassFilterParams.status = initIRIS.lowPassFilterParams.status;
            app.CutoffFreqEditField.Value = initIRIS.lowPassFilterParams.maxFreq;
            app.FilterOrderEditField.Value = initIRIS.lowPassFilterParams.filterOrder;
            app.SegmentTimeEditField.Value = CONF.SegmentTime;
            app.tMaxEditField.Value = CONF.tMax;
            app.OffSetEditField.Value = CONF.OffsetdB;
            app.DirectSoundThresholdEditField.Value = CONF.directSoundThresholdDB;
            app.LPFilterFcEditField.Value = CONF.LPfilterFc;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.CallingApp.IRISSettingButton.Enable = 'on';
            delete(app)
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            if strcmp(value, 'On')
                app.lowPassFilterParams.status = 'on';
                app.FilterOrderEditField.Editable = 'on';
                app.CutoffFreqEditField.Editable = 'on';
            else
                app.lowPassFilterParams.status = 'off';
                app.FilterOrderEditField.Editable = 'off';
                app.CutoffFreqEditField.Editable = 'off';
            end
        end

        % Button pushed function: AscertainButton
        function AscertainButtonPushed(app, event)
            d = uiprogressdlg(app.UIFigure, 'Title', '正在进行初始化设置', ...
                    'Message', '请您耐心等待', 'Indeterminate', 'on');

            app.lowPassFilterParams.maxFreq = app.CutoffFreqEditField.Value;
            app.lowPassFilterParams.filterOrder = app.FilterOrderEditField.Value;
            app.CallingApp.initIRIS.lowPassFilterParams = app.lowPassFilterParams;

            app.CallingApp.conf.OffsetdB = app.OffSetEditField.Value;
            app.CallingApp.conf.SegmentTime = app.SegmentTimeEditField.Value;
            app.CallingApp.conf.directSoundThresholdDB = app.DirectSoundThresholdEditField.Value;
            app.CallingApp.conf.tMax = app.tMaxEditField.Value;
            app.CallingApp.conf.LPfilterFc = app.LPFilterFcEditField.Value;
            app.CallingApp.conf.axis = eye(3);

%             app.CallingApp.IRISSourceIDSpinner.Enable = 'off';
            app.CallingApp.IRISSourceIDDropDown.Enable = 'off';
            app.CallingApp.IRISAziSlider.Enable = 'off';
            app.CallingApp.IRISEleSlider.Enable = 'off';
            app.CallingApp.IRISPlotButton.Enable = 'off';
            app.CallingApp.IRISExportButton.Enable = 'off';
            cla(app.CallingApp.IRISPlotUIAxes1);

            pause(.3)
            close(d);

            uialert(app.CallingApp.UIFigure, '已完成 < IR方向图 > 初始化设置！', '成功', 'Icon', 'success');

            app.CallingApp.IRISSettingButton.Enable = 'on';
            delete(app)
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.CallingApp.IRISSettingButton.Enable = 'on';
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.IRISSettingsPanel = uipanel(app.UIFigure);
            app.IRISSettingsPanel.Title = 'IR 方向图初始化设置';
            app.AscertainButton = uibutton(app.IRISSettingsPanel, 'push');
            app.AscertainButton.ButtonPushedFcn = createCallbackFcn(app, @AscertainButtonPushed, true);
            app.AscertainButton.Text = '确定';
            app.LPFilterSettingPanel = uipanel(app.IRISSettingsPanel);
            app.LPFilterSettingPanel.Title = ' 低通滤波器相关设置';
            app.SwitchLabel = uilabel(app.LPFilterSettingPanel);
            app.SwitchLabel.Text = {'是否开启'; '低通滤波器'};
            app.Switch = uiswitch(app.LPFilterSettingPanel, 'slider');
            app.Switch.Items = {'关', '开'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = '开';
            app.dBLabel_7 = uilabel(app.LPFilterSettingPanel);
            app.dBLabel_7.Text = '截止频率（Hz）：';
            app.CutoffFreqEditField = uieditfield(app.LPFilterSettingPanel, 'numeric');
            app.CutoffFreqEditField.Value = 10000;
            app.dBLabel_8 = uilabel(app.LPFilterSettingPanel);
            app.dBLabel_8.Text = '滤波器阶数：';
            app.FilterOrderEditField = uieditfield(app.LPFilterSettingPanel, 'numeric');
            app.FilterOrderEditField.Value = 50;
            app.IRISPlotSettingPanel = uipanel(app.IRISSettingsPanel);
            app.IRISPlotSettingPanel.Title = ' IRIS plot 相关设置';
            app.dBLabel_2 = uilabel(app.IRISPlotSettingPanel);
            app.dBLabel_2.Text = '时间间隔（s）：';
            app.SegmentTimeEditField = uieditfield(app.IRISPlotSettingPanel, 'numeric');
            app.SegmentTimeEditField.Value = 0.002;
            app.dBLabel_3 = uilabel(app.IRISPlotSettingPanel);
            app.dBLabel_3.Text = '声强截止幅度（dB）：';
            app.OffSetEditField = uieditfield(app.IRISPlotSettingPanel, 'numeric');
            app.OffSetEditField.Value = 40;
            app.dBLabel_4 = uilabel(app.IRISPlotSettingPanel);
            app.dBLabel_4.Text = '低通滤波器截止频率（Hz）：';
            app.LPFilterFcEditField = uieditfield(app.IRISPlotSettingPanel, 'numeric');
            app.LPFilterFcEditField.Value = 5000;
            app.dBLabel_5 = uilabel(app.IRISPlotSettingPanel);
            app.dBLabel_5.Text = '时间上限（s）：';
            app.tMaxEditField = uieditfield(app.IRISPlotSettingPanel, 'numeric');
            app.tMaxEditField.Value = 1.5;
            app.dBLabel_6 = uilabel(app.IRISPlotSettingPanel);
            app.dBLabel_6.Text = '直达声幅度阈值（dB）：';
            app.DirectSoundThresholdEditField = uieditfield(app.IRISPlotSettingPanel, 'numeric');
            app.DirectSoundThresholdEditField.Value = 30;
            app.CancelButton = uibutton(app.IRISSettingsPanel, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = '取消';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = IRISSettings(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end