classdef MainUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        LeftPanel                  matlab.ui.container.Panel
        LeftGridLayout             matlab.ui.container.GridLayout
        LoadIRPanel                matlab.ui.container.Panel
        IRPathLabel                matlab.ui.control.Label
        IRNameLabel                matlab.ui.control.Label
        LoadIRButton               matlab.ui.control.Button
        ComputePanel               matlab.ui.container.Panel
        StartComputeButton         matlab.ui.control.Button
        SaveComputeButton          matlab.ui.control.Button
        LoadComputeButton          matlab.ui.control.Button
        initSettingPanel           matlab.ui.container.Panel
        IRISSettingButton          matlab.ui.control.Button
        SourceLocSettingButton     matlab.ui.control.Button
        AmbiSettingButton          matlab.ui.control.Button
        PowermapSettingButton      matlab.ui.control.Button
        ChooseSourceIDLabel        matlab.ui.control.Label
        Label                      matlab.ui.control.Label
        MicArraySelectionDropDown  matlab.ui.control.DropDown
        Tree                       matlab.ui.container.Tree
        ChooseSourceIDNode         matlab.ui.container.TreeNode
        RightPanel                 matlab.ui.container.Panel
        TabGroup                   matlab.ui.container.TabGroup
        AmbiTab                    matlab.ui.container.Tab
        AmbiGridLayout             matlab.ui.container.GridLayout
        AmbiPlotSettingPanel       matlab.ui.container.Panel
        nSpinnerLabel              matlab.ui.control.Label
        Ambi_n_Spinner             matlab.ui.control.Spinner
        AmbiExportButton           matlab.ui.control.Button
        mSpinnerLabel              matlab.ui.control.Label
        Ambi_m_Spinner             matlab.ui.control.Spinner
        AmbiPlotButton             matlab.ui.control.Button
        IDDropDownLabel            matlab.ui.control.Label
        AmbiSourceIDDropDown       matlab.ui.control.DropDown
        AmbiPlotUIAxes             matlab.ui.control.UIAxes
        PowermapTab                matlab.ui.container.Tab
        PowermapGridLayout         matlab.ui.container.GridLayout
        PowermapPlotSettingPanel   matlab.ui.container.Panel
        PowermapExportButton       matlab.ui.control.Button
        PowermapPlotButton         matlab.ui.control.Button
        Slider_4Label              matlab.ui.control.Label
        PowermapAziSlider          matlab.ui.control.Slider
        Slider_4eleLabel           matlab.ui.control.Label
        PowermapEleSlider          matlab.ui.control.Slider
        IDDropDownLabel_2          matlab.ui.control.Label
        PowermapSourceIDDropDown   matlab.ui.control.DropDown
        PowermapPlotUIAxes         matlab.ui.control.UIAxes
        IRISTab                    matlab.ui.container.Tab
        IRISGridLayout             matlab.ui.container.GridLayout
        IRISPlotSettingPanel       matlab.ui.container.Panel
        Slider_3Label              matlab.ui.control.Label
        IRISAziSlider              matlab.ui.control.Slider
        Slider_3eleLabel           matlab.ui.control.Label
        IRISEleSlider              matlab.ui.control.Slider
        IRISExportButton           matlab.ui.control.Button
        IRISPlotButton             matlab.ui.control.Button
        IDDropDown_2Label          matlab.ui.control.Label
        IRISSourceIDDropDown       matlab.ui.control.DropDown
        IRISPlotUIAxes1            matlab.ui.control.UIAxes
        SourceLocTab               matlab.ui.container.Tab
        SourceLocGridLayout        matlab.ui.container.GridLayout
        SourceLocPlotSettingPanel  matlab.ui.container.Panel
        SliderLabel                matlab.ui.control.Label
        SourceLocAziSlider         matlab.ui.control.Slider
        Slider_2Label              matlab.ui.control.Label
        SourceLocEleSlider         matlab.ui.control.Slider
        SourcePositionsLabel       matlab.ui.control.Label
        SourceLocExportButton      matlab.ui.control.Button
        SourceLocPlotButton        matlab.ui.control.Button
        IDDropDown_2Label_2        matlab.ui.control.Label
        SourceLocSourceIDDropDown  matlab.ui.control.DropDown
        SourceLocPlotUIAxes        matlab.ui.control.UIAxes
        Panel                      matlab.ui.container.Panel
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        file
        path

        rawIR
        IR
        fs
        frameLen = 1024;
        sphHarmType = 'complex';

        micParams
        sma2sh
        sh2srp
        sh2slai
        PowermapAzi
        PowermapEle

        AmbiSettingsApp
        PowermapSettingsApp
        IRISSettingsApp
        SourceLocSettingsApp

        confDirect = struct;
        sndHedgehog
        wxyz

        SRIR
        azelAll
        srpCum_dB
        srcPos

        micArrayType
        SourceNum % Number of Selected Sources
        SourceIdxAll % Selected Sources
        rawSourceNum
        rawSourceIdxAll
        
        sourceNumAll
        sourceIDNodes = {}
        
        loadIRFlag
        loadComputeFlag
        
        measurementNum
    end
    
    properties (Access = public)
        maxOrder
        initAmbi = struct;
        initPowermap = struct;
        initIRIS = struct;
        initSourceLoc = struct;
        
        conf = struct;
    end

    methods (Access = private)

        function AmbiPlot(app)
            d = uiprogressdlg(app.UIFigure, 'Title', '绘图中', ...
                    'Message', '请您耐心等待', 'Indeterminate', 'on');

            srcIdx = find(str2num(app.AmbiSourceIDDropDown.Value) == app.SourceIdxAll);

            n = app.Ambi_n_Spinner.Value;
            m = app.Ambi_m_Spinner.Value;
            srir = app.SRIR{srcIdx};
            [nrow,~] = size(srir);

            plot(app.AmbiPlotUIAxes,(0:nrow-1)./app.fs, srir(:,(n+1).^2-n+m), 'LineWidth', 1);
            xlabel(app.AmbiPlotUIAxes, '时间（s）');
            ylabel(app.AmbiPlotUIAxes, '');
            title(app.AmbiPlotUIAxes, strcat("SRIR (SourceID=", app.AmbiSourceIDDropDown.Value, "  n=", num2str(n), "  m=", num2str(m), ")"));          

            disableDefaultInteractivity(app.AmbiPlotUIAxes);
            h1 = rotate3d(app.AmbiPlotUIAxes);
            h1.Enable = 'off';
            
            pause(.3)
            close(d);
        end


        function PowermapPlot(app)
            d = uiprogressdlg(app.UIFigure, 'Title', '绘图中', ...
                    'Message', '请您耐心等待', 'Indeterminate', 'on');

            srcIdx = find(str2num(app.PowermapSourceIDDropDown.Value) == app.SourceIdxAll);

            figure(app.UIFigure)
%             srpSurf = surf(app.PowermapPlotUIAxes, app.PowermapAzi./pi.*180, app.PowermapEle./pi.*180, app.srpCum_dB{srcIdx}, 'EdgeColor', [0.7 0.7 0.7], 'EdgeAlpha', 0.5);
%             srpSurf = surf(app.PowermapPlotUIAxes, app.PowermapAzi./pi.*180, app.PowermapEle./pi.*180, app.srpCum_dB{srcIdx}, 'EdgeColor', 'interp');            
            srpSurf = surf(app.PowermapPlotUIAxes, app.PowermapAzi./pi.*180, app.PowermapEle./pi.*180, app.srpCum_dB{srcIdx});        
            shading(app.PowermapPlotUIAxes, 'interp');
            xlim(app.PowermapPlotUIAxes, [-180 180]);
            ylim(app.PowermapPlotUIAxes, [-90 90]);
            xlabel(app.PowermapPlotUIAxes, '方位角（°）');
            ylabel(app.PowermapPlotUIAxes, '俯仰角（°）');
            zlabel(app.PowermapPlotUIAxes, '声能（dB）')


            view(app.PowermapPlotUIAxes, app.PowermapAziSlider.Value, app.PowermapEleSlider.Value)

            numPeak = 1;
            [~, ~, zPeak, xPeakId, yPeakId] = findpeaks2D(app.PowermapAzi, app.PowermapEle, app.srpCum_dB{srcIdx}, numPeak, 0, 2);
            for ii = 1:numel(zPeak)
                datatip(srpSurf,'DataIndex',size(app.srpCum_dB{srcIdx},1)*(xPeakId(ii)-1)+yPeakId(ii));
            end

            disableDefaultInteractivity(app.PowermapPlotUIAxes);
            h2 = rotate3d(app.PowermapPlotUIAxes);
            h2.ActionPostCallback = @app.myPowermapPostCallBack;
            h2.Enable = 'on';

            pause(.3)
            close(d);
        end
        
        
        function IRISPlot(app)

            d = uiprogressdlg(app.UIFigure, 'Title', '绘图中', ...
                    'Message', '请您耐心等待', 'Indeterminate', 'on');

            srcIdx = find(str2num(app.IRISSourceIDDropDown.Value) == app.SourceIdxAll);

            figure(app.UIFigure)
            cla(app.IRISPlotUIAxes1)
            tMax = app.conf.tMax;
            axes(app.IRISPlotUIAxes1);
            ArrivalTimeColour = app.conf.ArrivalTimeColour;

            SndHedgehog = app.sndHedgehog{srcIdx};

            lgFlag = zeros(1,numel(ArrivalTimeColour));
            DisplayName = cell(1,size(SndHedgehog,1));
            for jj = 1:size(SndHedgehog, 1)
                TimeIntervalStart = 0;
                for ii = 1:numel(ArrivalTimeColour)
                    TimeIntervalEnd = min(tMax,1e-3*ArrivalTimeColour(ii).TimeIntervalEnd);
                    if (TimeIntervalStart<=SndHedgehog(jj,5))&&(SndHedgehog(jj,5)<TimeIntervalEnd)
                        RGB = ArrivalTimeColour(ii).Colour;
                        [x,y,z] = sph2cart(SndHedgehog(jj,1),SndHedgehog(jj,2),SndHedgehog(jj,4)+app.conf.OffsetdB);
                        if lgFlag(ii) == 0
                            DisplayName{jj} = [num2str(1000*TimeIntervalStart),' - ',num2str(1000*TimeIntervalEnd)];
                            lgFlag(ii) = 1;
                        else
                            DisplayName{jj} = '';
                        end
                        plot3(app.IRISPlotUIAxes1, [0 x],[0 y],[0 z],'Color',[RGB.R RGB.G RGB.B]./255,'LineWidth',3);

                        break;
                    end
                    TimeIntervalStart = TimeIntervalEnd;
                end
            end
            xlabel(app.IRISPlotUIAxes1, 'X(dB)');
            ylabel(app.IRISPlotUIAxes1, 'Y(dB)');
            zlabel(app.IRISPlotUIAxes1, 'Z(dB)');
            axis(app.IRISPlotUIAxes1, app.conf.OffsetdB*[-1 1 -1 1 -1 1]);
            axis(app.IRISPlotUIAxes1, 'equal');
            legend21b(app.IRISPlotUIAxes1, DisplayName{:});
            view(app.IRISPlotUIAxes1, app.IRISAziSlider.Value, app.IRISEleSlider.Value)

            disableDefaultInteractivity(app.IRISPlotUIAxes1);
            h31 = rotate3d(app.IRISPlotUIAxes1);
            h31.ActionPostCallback = @app.myIRISPostCallBack;
            h31.Enable = 'on';
            
            pause(.3)
            close(d);
        end


        function SourceLocPlot(app)
            d = uiprogressdlg(app.UIFigure, 'Title', '绘图中', ...
                    'Message', '请您耐心等待', 'Indeterminate', 'on');

            POSData = app.initSourceLoc;

            % plot
            figure(app.UIFigure)
            p1 = plot3(app.SourceLocPlotUIAxes, POSData.ESMAPos(1), POSData.ESMAPos(2), POSData.ESMAPos(3), ...
                'o', 'Color', 'b', 'MarkerSize', 20, 'MarkerFaceColor','#D9FFFF');
            text(app.SourceLocPlotUIAxes, POSData.ESMAPos(1), POSData.ESMAPos(2), POSData.ESMAPos(3), ...
                app.micArrayType, 'FontWeight', 'bold', 'Color', '#7E2F8E');
            p2 = plot3(app.SourceLocPlotUIAxes, app.srcPos(1,:), app.srcPos(2,:), app.srcPos(3,:), ...
                'ro', 'MarkerFaceColor', '#FFB6C1');
            for i = 1:app.SourceNum
                str{i} = ['Loudspeaker ', num2str(app.SourceIdxAll(i))];
            end
            text(app.SourceLocPlotUIAxes, app.srcPos(1,:)+0.06, app.srcPos(2,:)+0.06, app.srcPos(3,:)+0.06, ...
                str, 'FontWeight', 'bold', 'Color', '#A2142F');
            daspect(app.SourceLocPlotUIAxes, [1 1 1])
            xlabel(app.SourceLocPlotUIAxes, "X轴")
            ylabel(app.SourceLocPlotUIAxes, "Y轴")
            zlabel(app.SourceLocPlotUIAxes, "Z轴")
            title(app.SourceLocPlotUIAxes, "声源位置")
            view(app.SourceLocPlotUIAxes, app.SourceLocAziSlider.Value, app.SourceLocEleSlider.Value)

            disableDefaultInteractivity(app.SourceLocPlotUIAxes);
            h4 = rotate3d(app.SourceLocPlotUIAxes);
            h4.ActionPostCallback = @app.mySourceLocPostCallBack;
            h4.Enable = 'on';

            pause(.3)
            close(d);
        end
       
        
        function myPowermapPostCallBack(app, fig, evd)
            app.PowermapAziSlider.Value = evd.Axes.View(1);
            app.PowermapEleSlider.Value = evd.Axes.View(2);
        end
        
        
        function myIRISPostCallBack(app, fig, evd)
            app.IRISAziSlider.Value = evd.Axes.View(1);
            app.IRISEleSlider.Value = evd.Axes.View(2);
        end

        function mySourceLocPostCallBack(app, fig, evd)
            app.SourceLocAziSlider.Value = evd.Axes.View(1);
            app.SourceLocEleSlider.Value = evd.Axes.View(2);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            cd(fileparts(mfilename('fullpath')));
            addpath(genpath('../'));
            
                
            % UI settings
            app.AmbiSourceIDDropDown.Enable = 'off';
            app.Ambi_n_Spinner.Enable = 'off';
            app.Ambi_m_Spinner.Enable = 'off';
            app.AmbiPlotButton.Enable = 'off';
            app.AmbiExportButton.Enable = 'off';

            app.PowermapSourceIDDropDown.Enable = 'off';
            app.PowermapAziSlider.Enable = 'off';
            app.PowermapEleSlider.Enable = 'off';
            app.PowermapPlotButton.Enable = 'off';
            app.PowermapExportButton.Enable = 'off';

            app.IRISSourceIDDropDown.Enable = 'off';
            app.IRISAziSlider.Enable = 'off';
            app.IRISEleSlider.Enable = 'off';
            app.IRISPlotButton.Enable = 'off';
            app.IRISExportButton.Enable = 'off';

            app.SourceLocSourceIDDropDown.Enable = 'off';
            app.SourceLocAziSlider.Enable = 'off';
            app.SourceLocEleSlider.Enable = 'off';
            app.SourceLocPlotButton.Enable = 'off';
            app.SourceLocExportButton.Enable = 'off';

            app.SaveComputeButton.Enable = 'off';
            app.StartComputeButton.Enable = 'off';

            % init ldspk & mic
            app.micArrayType = 'ESMA';
            app.rawSourceIdxAll = [];
            app.rawSourceNum = 0;
            app.sourceNumAll = 0;

            % init Ambi
            app.maxOrder = 8;
            app.Ambi_n_Spinner.Limits = [0 app.maxOrder];
            app.initAmbi.MaxGain = 20;
            app.initAmbi.FilterOrder = 256;

            % init Powermap
            app.initPowermap.dAngle = 1;
            app.initPowermap.aziMin = -180;
            app.initPowermap.aziMax = 180;
            app.initPowermap.eleMin = -90;
            app.initPowermap.eleMax = 90;
            app.initPowermap.lowPassFilterParams.status = 'on';
            app.initPowermap.lowPassFilterParams.maxFreq = 10000;
            app.initPowermap.lowPassFilterParams.filterOrder = 50;

            % init IRIS
            app.initIRIS.lowPassFilterParams.status = 'on';
            app.initIRIS.lowPassFilterParams.maxFreq = 10000;
            app.initIRIS.lowPassFilterParams.filterOrder = 50;
            app.conf.OffsetdB = 40;
            app.conf.SegmentTime = 0.002;
            app.conf.directSoundThresholdDB = 30;
            app.conf.tMax = 1.5;
            app.conf.LPfilterFc = 5000;
            app.conf.axis = eye(3);

            % init SourceLoc
            app.initSourceLoc.ESMAPos = [0 0 1.2];
            app.initSourceLoc.refMicIdx = 5;
            app.initSourceLoc.refSpkIdx = 1;           
            app.initSourceLoc.rDistance = 0;
%             app.initSourceLoc.refSpkPosReal = [0.98 -1.8 0.1];

            app.loadIRFlag = false;
            app.loadComputeFlag = false;

        end

        % Button pushed function: LoadIRButton
        function LoadIRButtonPushed(app, event)
            [app.file, app.path] = uigetfile('*.mat');
            
            if ~isequal(app.file, 0)
                
                figure(app.UIFigure)
                d = uiprogressdlg(app.UIFigure, 'Title', '导入中', ...
                    'Message', '请您耐心等待', 'Indeterminate', 'on');
                
                dataPath0 = fullfile(app.path, app.file);
                
                try
                    dataPath0 = ita2irm(dataPath0, 0);
                    chIdx = 1:16;
                    dataPath = mergeData(dataPath0, chIdx, 0);
                    
%                   Data = importdata(dataPath);
                    load(dataPath,'Data','measurementNum');
                    app.measurementNum = measurementNum;%# ok
                    app.rawIR = cell2mat3(Data{1}.IR);
                    app.fs = Data{1}.fs;
                    
                    app.rawSourceIdxAll = [];
                    app.rawSourceNum = 0;
                    app.sourceNumAll = size(app.rawIR,2);
                    
                    if ~isempty(app.sourceIDNodes)
                        for i = 1:length(app.sourceIDNodes)
                            app.sourceIDNodes{i}.delete;
                        end
                    end
                    for i = 1:app.sourceNumAll
                        app.sourceIDNodes{i} = uitreenode(app.ChooseSourceIDNode, 'Text', ['声源 ' num2str(i)], 'NodeData', i);
                    end
                    
                    app.ChooseSourceIDNode.expand;
                    
                    str = sprintf("已成功导入\n%s", app.file);
                    app.IRNameLabel.Text = str;
                    
                    app.loadIRFlag = true;
                    app.loadComputeFlag = false;
                    app.StartComputeButton.Enable = 'on';
                    
                    pause(2)
                    close(d)
                    
                catch
                    close(d)
                    uialert(app.UIFigure, '无法识别 IR 文件，请在 MATLAB 中安装 ITA 工具箱！', '错误');
                end
            end
            
            figure(app.UIFigure)
             
        end

        % Button pushed function: AmbiPlotButton
        function AmbiPlotButtonPushed(app, event)
            AmbiPlot(app);
            app.AmbiPlotButton.Enable = 'off';
        end

        % Button pushed function: AmbiSettingButton
        function AmbiSettingButtonPushed(app, event)
            % Disable the button while dialog is open
            app.AmbiSettingButton.Enable = 'off';
            
            % Open the options dialog and pass inputs
            app.AmbiSettingsApp = AmbiSettings(app, app.maxOrder, app.initAmbi);
            
        end

        % Button pushed function: PowermapSettingButton
        function PowermapSettingButtonPushed(app, event)
            % Disable the button while dialog is open
            app.PowermapSettingButton.Enable = 'off';

            
            % Open the options dialog and pass inputs
            app.PowermapSettingsApp = PowermapSettings(app, app.initPowermap);

        end

        % Button pushed function: IRISSettingButton
        function IRISSettingButtonPushed(app, event)
            % Disable the button while dialog is open
            app.IRISSettingButton.Enable = 'off';
            

            % Open the options dialog and pass inputs
            app.IRISSettingsApp = IRISSettings(app, app.initIRIS, app.conf);

        end

        % Button pushed function: SourceLocSettingButton
        function SourceLocSettingButtonPushed(app, event)
         
            if app.rawSourceNum <= 0 
                uialert(app.UIFigure, '请选择声源 ID！', '错误');
            elseif app.rawSourceNum < 2
                uialert(app.UIFigure, '所选声源的数目不能少于两个！', '错误');
            else
                app.SourceLocSettingButton.Enable = 'off';
                app.SourceLocSettingsApp = SourceLocSettings(app, app.rawSourceIdxAll, app.initSourceLoc);
            end
            
        end

        % Value changing function: PowermapAziSlider
        function PowermapAziSliderValueChanging(app, event)
            changingValue = event.Value;
            view(app.PowermapPlotUIAxes, changingValue, app.PowermapEleSlider.Value);
        end

        % Value changing function: PowermapEleSlider
        function PowermapEleSliderValueChanging(app, event)
            changingValue = event.Value;
            view(app.PowermapPlotUIAxes, app.PowermapAziSlider.Value, changingValue);
        end

        % Value changing function: IRISAziSlider
        function IRISAziSliderValueChanging(app, event)
            changingValue = event.Value;
            view(app.IRISPlotUIAxes1, changingValue, app.IRISEleSlider.Value);
        end

        % Value changing function: IRISEleSlider
        function IRISEleSliderValueChanging(app, event)
            changingValue = event.Value;
            view(app.IRISPlotUIAxes1, app.IRISAziSlider.Value, changingValue);
        end

        % Button pushed function: SourceLocPlotButton
        function SourceLocPlotButtonPushed(app, event)
            SourceLocPlot(app);
            app.SourceLocPlotButton.Enable = 'off';
        end

        % Value changing function: SourceLocAziSlider
        function SourceLocAziSliderValueChanging(app, event)
            changingValue = event.Value;
            view(app.SourceLocPlotUIAxes, changingValue, app.SourceLocEleSlider.Value);
        end

        % Value changing function: SourceLocEleSlider
        function SourceLocEleSliderValueChanging(app, event)
            changingValue = event.Value;
            view(app.SourceLocPlotUIAxes, app.SourceLocAziSlider.Value, changingValue);
        end

        % Button pushed function: IRISPlotButton
        function IRISPlotButtonPushed(app, event)
            IRISPlot(app);
            app.IRISPlotButton.Enable = 'off';
        end

        % Button pushed function: PowermapPlotButton
        function PowermapPlotButtonPushed(app, event)
            PowermapPlot(app);
            app.PowermapPlotButton.Enable = 'off';
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.AmbiSettingsApp)
            delete(app.PowermapSettingsApp)
            delete(app.IRISSettingsApp)
            delete(app.SourceLocSettingsApp)
            delete(app)
        end

        % Button pushed function: PowermapExportButton
        function PowermapExportButtonPushed(app, event)
            selection = uiconfirm(app.UIFigure,'您是否要将数据导出?','提醒',...
                'Icon', 'warning', 'Options', {'是','否'},...
                'DefaultOption',1,'CancelOption',2);

            if strcmp(selection, "是")
                [FILE, PATH, indx] = uiputfile('*.mat','导出数据','SteeredResponsePowerIndB.mat');
                figure(app.UIFigure)

                if indx == 1

                    d = uiprogressdlg(app.UIFigure, 'Title', '运行中', ...
                        'Message', '正在保存数据', 'Indeterminate', 'on');

                    SteeredResponsePower = struct;
                    SteeredResponsePower.maxOrder = app.maxOrder;
                    SteeredResponsePower.selectedSourceID = app.SourceIdxAll;
                    SteeredResponsePower.AzimuthInRad = app.PowermapAzi;
                    SteeredResponsePower.ElevationInRad = app.PowermapEle;
                    SteeredResponsePower.srpCum_dB = app.srpCum_dB;
                    save(fullfile(PATH, FILE), 'SteeredResponsePower');

                    pause(0.5);
                    close(d)

                    pause(0.1);
                    uialert(app.UIFigure, '已全部导出！', '成功', 'Icon', 'success');
                end
            end
        end

        % Button pushed function: IRISExportButton
        function IRISExportButtonPushed(app, event)
            selection = uiconfirm(app.UIFigure,'您是否要将数据导出?','提醒',...
                'Icon', 'warning', 'Options', {'是','否'},...
                'DefaultOption',1,'CancelOption',2);

            if strcmp(selection, "是")
                [FILE, PATH, indx] = uiputfile('*.mat','导出数据','SpatiallyLocalisedActiveIntensity.mat');
                figure(app.UIFigure)

                if indx == 1

                    d = uiprogressdlg(app.UIFigure, 'Title', '运行中', ...
                        'Message', '正在保存数据', 'Indeterminate', 'on');

                    SLAIvec = struct;
                    SLAIvec.maxOrder = app.maxOrder;
                    SLAIvec.selectedSourceID = app.SourceIdxAll;
                    SLAIvec.SLAIvector = app.wxyz;
                    save(fullfile(PATH, FILE), 'SLAIvec');

                    pause(0.5);
                    close(d)

                    pause(0.1);
                    uialert(app.UIFigure, '已全部导出！', '成功', 'Icon', 'success');
                end
            end
        end

        % Button pushed function: SourceLocExportButton
        function SourceLocExportButtonPushed(app, event)
            selection = uiconfirm(app.UIFigure,'您是否要将数据导出?','提醒',...
                'Icon', 'warning', 'Options', {'是','否'},...
                'DefaultOption',1,'CancelOption',2);

            if strcmp(selection, "是")
                [FILE, PATH, indx] = uiputfile('*.json','导出数据','DirectionOfArrival_SoundSourcePositions.json');
                figure(app.UIFigure)

                if indx == 1

                    d = uiprogressdlg(app.UIFigure, 'Title', '运行中', ...
                        'Message', '正在保存数据', 'Indeterminate', 'on');

                    srcPOS = app.srcPos;
                    jsonData = struct;
                    jsonData.selectedSourceID = app.SourceIdxAll;
                    jsonData.x = srcPOS(1,:);
                    jsonData.y = srcPOS(2,:);
                    jsonData.z = srcPOS(3,:);
                    jsonTxt = jsonencode(jsonData);
                    fileID = fopen(strcat(PATH, FILE), 'w');
                    fprintf(fileID, jsonTxt);
                    fclose(fileID);

                    pause(0.5);
                    close(d)

                    pause(0.1);
                    uialert(app.UIFigure, '已全部导出！', '成功', 'Icon', 'success');
                end
            end
        end

        % Value changed function: Ambi_n_Spinner
        function Ambi_n_SpinnerValueChanged(app, event)
            app.Ambi_m_Spinner.Enable = 'on';
            if app.Ambi_n_Spinner.Value ~= 0
                app.Ambi_m_Spinner.Limits = [-app.Ambi_n_Spinner.Value app.Ambi_n_Spinner.Value];
            else
                app.Ambi_m_Spinner.Value = 0;
                app.Ambi_m_Spinner.Enable = 'off';
            end
            app.AmbiPlotButton.Enable = 'on';
        end

        % Value changed function: Ambi_m_Spinner
        function Ambi_m_SpinnerValueChanged(app, event)
            app.AmbiPlotButton.Enable = 'on';
        end

        % Button pushed function: AmbiExportButton
        function AmbiExportButtonPushed(app, event)
            selection = uiconfirm(app.UIFigure,'您是否要将数据导出?','提醒',...
                'Icon', 'warning', 'Options', {'是','否'},...
                'DefaultOption',1,'CancelOption',2);

            if strcmp(selection, "是")
                [FILE, PATH, indx] = uiputfile('*.mat','导出数据','Ambisonics.mat');
                figure(app.UIFigure)
                
                if indx == 1

                    d = uiprogressdlg(app.UIFigure, 'Title', '运行中', ...
                        'Message', '正在保存数据', 'Indeterminate', 'on');

                    Ambisonics = struct;
                    Ambisonics.maxOrder = app.maxOrder;
                    Ambisonics.selectedSourceID = app.SourceIdxAll;
                    Ambisonics.SRIR = app.SRIR;
                    save(fullfile(PATH, FILE), 'Ambisonics');

                    close(d);

                    pause(0.1);
                    uialert(app.UIFigure, '已全部导出！', '成功', 'Icon', 'success');
                end
            end

        end

        % Button pushed function: StartComputeButton
        function StartComputeButtonPushed(app, event)
            selection = uiconfirm(app.UIFigure,'可能需要较长的时间，您是否要开始运算?','提醒',...
                'Icon', 'warning', 'Options', {'是','否'},...
                'DefaultOption',1,'CancelOption',2);
            if strcmp(selection, '是')
                
                d = uiprogressdlg(app.UIFigure, 'Title', '运算中', ...
                    'Message', '已开始运算，请您耐心等待…');
                
%                 d = uiprogressdlg(app.UIFigure, 'Title', '运算中', ...
%                     'Message', '请您耐心等待', 'Indeterminate', 'on');
                
                app.SourceNum = app.rawSourceNum;
                app.SourceIdxAll = app.rawSourceIdxAll;
                
                if app.SourceNum >= 2
                    if app.initSourceLoc.rDistance ~= 0
                        
                        if (~app.loadIRFlag) && (app.loadComputeFlag)
                            dataPath0 = fullfile(app.path, app.file);
                            if isfile(dataPath0)
                                try
                                    dataPath0 = ita2irm(dataPath0, 0);
                                catch
                                    close(d)
                                    uialert(app.UIFigure, '无法识别 IR 文件，请在 MATLAB 中安装 ITA 工具箱！', '错误');
                                    return;
                                end
                                chIdx = 1:16;
                                dataPath = mergeData(dataPath0, chIdx, 0);
                                
%                         Data = importdata(dataPath);
                                load(dataPath,'Data','measurementNum');
                                app.measurementNum = measurementNum;%# ok
                                app.rawIR = cell2mat3(Data{1}.IR);
                                app.fs = Data{1}.fs;
                            else
                                close(d)
                                uialert(app.UIFigure, '导入运算结果中存储的 IR 文件路径有误，请重新浏览 IR 文件！', '错误');
                                return;
                            end
                        end
                        
                        app.IR = app.rawIR(:,app.SourceIdxAll,:);
                        
                        %% init Ambi
                        app.micParams = MicArrayPresets(app.micArrayType, app.measurementNum, app.maxOrder,0);
                        
                        app.sma2sh = SMA2SH(app.micParams, app.initAmbi.MaxGain, app.fs, app.initAmbi.FilterOrder, 'sphHarmType', app.sphHarmType);
                        app.sma2sh.setup;
                        
                        %% init Powermap
                        dAngle = app.initPowermap.dAngle;
                        azimuth = linspace(app.initPowermap.aziMin/180*pi, app.initPowermap.aziMax/180*pi, 360/dAngle+1);
                        elevation = linspace(app.initPowermap.eleMin/180*pi, app.initPowermap.eleMax/180*pi, 180/dAngle+1);
                        app.PowermapAzi = azimuth;
                        app.PowermapEle = elevation;
                        
                        patternWeight = [];
                        app.initPowermap.lowPassFilterParams.fs = app.fs;
                        app.sh2srp = SH2SRP(azimuth, elevation, app.maxOrder, app.initPowermap.lowPassFilterParams, patternWeight, 'sphHarmType', app.sphHarmType);
                        app.sh2srp.setup;
                        
                        %% init IRIS plot
                        sectorAzimuth = [0];
                        sectorElevation = [0];
                        patternWeight = 'omni';
                        
                        confIRIS = readstruct('measurement.xml');
                        app.conf.fs = app.fs;
                        app.initIRIS.lowPassFilterParams.fs = app.fs;
                        app.conf.ArrivalTimeColour = confIRIS.Project.ResultsViewData.IrisPlotViewData.ArrivalTimeColoursList.ArrivalTimeColours(1).ArrivalTimeColourList.ArrivalTimeColourItem;
                        
                        app.sh2slai = SH2SLAI(sectorAzimuth, sectorElevation, app.maxOrder-1, app.initIRIS.lowPassFilterParams, patternWeight, 'sphHarmType', app.sphHarmType);
                        app.sh2slai.setup;
                        
                        app.confDirect.fs = app.conf.fs;
                        app.confDirect.windowLength = 1000 * app.conf.SegmentTime;
                        app.confDirect.threshold = -app.conf.directSoundThresholdDB;
                        
                        %% Calculation
                        sizeIR2 = size(app.IR,2);
                        app.SRIR = cell(sizeIR2,1);
                        app.srpCum_dB = cell(sizeIR2,1);
                        app.wxyz = cell(sizeIR2,1);
                        app.azelAll = zeros(sizeIR2,2);
                        
                        d.Value = 0.1;
                        d.Message = strcat('已完成', num2str(round(d.Value * 100)), '%，请您耐心等待…');
                        
                        for srcIdx = 1:sizeIR2
                            ir = squeeze(app.IR(:,srcIdx,:)).';
                            sh = frameProcessor(app.sma2sh, ir, app.frameLen);
                            app.SRIR{srcIdx} = real(complex2realCoeffs(sh.')).';
                            srp = frameProcessor(app.sh2srp, sh, app.frameLen, app.frameLen, 3);
                            app.srpCum_dB{srcIdx} = 0.5*mag2db(mean((db2mag(srp)).^2,3));
                            WXYZ = real(frameProcessor(app.sh2slai, sh, app.frameLen));
                            app.wxyz{srcIdx} = WXYZ;
                            
                            [directIdx, ~] = getDirectIR(WXYZ(:,1), app.confDirect);
                            app.conf.onsetIdx = directIdx(1);
                            SNDHedgehog = getSndHedgehog(WXYZ, app.conf);
                            SNDHedgehog(:,1:2) = transAzEl(SNDHedgehog(:,1:2), app.conf.axis);
                            app.sndHedgehog{srcIdx} = SNDHedgehog;
                            
                            [srpEl,idxEl] = max(app.srpCum_dB{srcIdx});
                            [~,idxAz] = max(srpEl);
                            idxEl = idxEl(idxAz);
                            azel = [azimuth(idxAz)./pi.*180,elevation(idxEl)./pi.*180];
                            app.azelAll(srcIdx,:) = azel;
                            
                            d.Value = 0.85 * srcIdx / sizeIR2 + 0.05;
                            d.Message = strcat('已完成', num2str(round(d.Value * 100)), '%，请您耐心等待…');
                        end
                        
                        %% init SourceLoc
                        ESMAPos = app.initSourceLoc.ESMAPos.';
                        refMicIdx = app.initSourceLoc.refMicIdx;
                        refSpkIdxReal = app.initSourceLoc.refSpkIdx;
                        refSpkIdx = find(refSpkIdxReal == app.SourceIdxAll);
                        [refMicPos(1,:),refMicPos(2,:),refMicPos(3,:)] = sph2cart(app.micParams.azi(refMicIdx), app.micParams.ele(refMicIdx), app.micParams.radius);
                        refMicPos = ESMAPos + refMicPos;
%                 refSpkPosReal = app.initSourceLoc.refSpkPosReal.';
%                 rEnd = vecnorm(refSpkPosReal-refMicPos,2,1);
                        rDistance = app.initSourceLoc.rDistance;
                        c = 343;
                        azelAll_rad = app.azelAll./180.*pi;
                        r = rDistance + c*getTDOA4GUI(squeeze(app.IR(refMicIdx,:,:)).', app.fs, refSpkIdx);
                        app.srcPos = aziEleDis2Pos(azelAll_rad.', ESMAPos, r, refMicPos);
                        
                        %% UI settings
                        cla(app.AmbiPlotUIAxes);
                        cla(app.PowermapPlotUIAxes);
                        cla(app.IRISPlotUIAxes1);
                        cla(app.SourceLocPlotUIAxes);
                        
                        app.AmbiSourceIDDropDown.Enable = 'on';
                        app.Ambi_n_Spinner.Enable = 'on';
                        app.AmbiPlotButton.Enable = 'on';
                        app.AmbiExportButton.Enable = 'on';
                        
                        app.PowermapSourceIDDropDown.Enable = 'on';
                        app.PowermapAziSlider.Enable = 'on';
                        app.PowermapEleSlider.Enable = 'on';
                        app.PowermapPlotButton.Enable = 'on';
                        app.PowermapExportButton.Enable = 'on';
                        
                        app.IRISSourceIDDropDown.Enable = 'on';
                        app.IRISAziSlider.Enable = 'on';
                        app.IRISEleSlider.Enable = 'on';
                        app.IRISPlotButton.Enable = 'on';
                        app.IRISExportButton.Enable = 'on';
                        app.SourceLocSourceIDDropDown.Enable = 'on';
                        app.SourceLocAziSlider.Enable = 'on';
                        app.SourceLocEleSlider.Enable = 'on';
                        app.SourceLocPlotButton.Enable = 'on';
                        app.SourceLocExportButton.Enable = 'on';
                        
                        app.SaveComputeButton.Enable = 'on';
                        
                        app.AmbiSourceIDDropDown.Items = sprintfc('%d', app.SourceIdxAll);
                        app.PowermapSourceIDDropDown.Items = sprintfc('%d', app.SourceIdxAll);
                        app.IRISSourceIDDropDown.Items = sprintfc('%d', app.SourceIdxAll);
                        app.SourceLocSourceIDDropDown.Items = sprintfc('%d', app.SourceIdxAll);
                        
                        srcIdx = find(str2num(app.SourceLocSourceIDDropDown.Value) == app.SourceIdxAll);
                        app.SourcePositionsLabel.Text = strcat("声源位置（m）：", num2str(round(app.srcPos(:,srcIdx).', 2)));
                        
                        pause(2)
                        d.Value = 1;
                        close(d)
                        uialert(app.UIFigure, '运算完毕！', '成功', 'Icon', 'success');
                        
                    else
                        uialert(app.UIFigure, '请重新设置 <声源定位> 中参考传声器与参考扬声器之间的距离！', '错误')
                    end
                else
                    uialert(app.UIFigure, '所选声源的数目不能少于两个！', '错误');
                end
            end

        end

        % Button pushed function: SaveComputeButton
        function SaveComputeButtonPushed(app, event)
            selection = uiconfirm(app.UIFigure,'您是否要保存运算结果?','提醒',...
                'Icon', 'warning', 'Options', {'是','否'},...
                'DefaultOption',1,'CancelOption',2);

            if strcmp(selection, "是")
                [FILE, PATH, indx] = uiputfile('*.mat','保存运算结果','ComputingResults.mat');
                figure(app.UIFigure)
                
                if indx == 1

                    d = uiprogressdlg(app.UIFigure, 'Title', '运行中', ...
                        'Message', '正在保存运算结果', 'Indeterminate', 'on');

                    ComputingResults = struct;
                    ComputingResults.isSaved= true;
                    
                    ComputingResults.micArrayType = app.micArrayType;
                    
                    ComputingResults.maxOrder = app.maxOrder;
                    ComputingResults.fs = app.fs;
                    ComputingResults.sourceNumAll = app.sourceNumAll;
                    ComputingResults.SourceNum = app.SourceNum;
                    ComputingResults.SourceIdxAll = app.SourceIdxAll;
                    ComputingResults.IRpath = app.path;
                    ComputingResults.IRfile = app.file;
                    
                    ComputingResults.initAmbi = app.initAmbi;
                    ComputingResults.initPowermap = app.initPowermap;
                    ComputingResults.initIRIS = app.initIRIS;
                    ComputingResults.initSourceLoc = app.initSourceLoc;

                    ComputingResults.SRIR = app.SRIR;

                    ComputingResults.PowermapAzi = app.PowermapAzi;
                    ComputingResults.PowermapEle = app.PowermapEle;
                    ComputingResults.srpCum_dB = app.srpCum_dB;

                    ComputingResults.conf = app.conf;
                    ComputingResults.sndHedgehog = app.sndHedgehog;
                    ComputingResults.wxyz = app.wxyz;

                    ComputingResults.initSourceLoc = app.initSourceLoc;
                    ComputingResults.srcPos = app.srcPos;

                    save(fullfile(PATH, FILE), 'ComputingResults');

                    close(d);

                    pause(0.1);
                    uialert(app.UIFigure, '已全部导出！', '成功', 'Icon', 'success');
                end
            end
        end

        % Button pushed function: LoadComputeButton
        function LoadComputeButtonPushed(app, event)
            selection = uiconfirm(app.UIFigure,'您是否要直接导入历史运算结果？若确认执行，您此前的设置或运算操作都将失效。','提醒',...
                'Icon', 'warning', 'Options', {'是','否'},...
                'DefaultOption',1,'CancelOption',2);

            if strcmp(selection, "是")

                [File, Path] = uigetfile('*.mat');

                figure(app.UIFigure)

                if ~isequal(File, 0)
                    d = uiprogressdlg(app.UIFigure, 'Title', '运行中', ...
                        'Message', '正在导入历史运算结果', 'Indeterminate', 'on');

                    ComputingResults = importdata(fullfile(Path, File));

                    if isfield(ComputingResults, 'isSaved')
                        app.maxOrder = ComputingResults.maxOrder;
                        app.micArrayType = ComputingResults.micArrayType;
                        app.MicArraySelectionDropDown.Value = app.micArrayType;
                        app.Ambi_n_Spinner.Limits = [0 app.maxOrder];
                        app.fs = ComputingResults.fs;
                        app.sourceNumAll = ComputingResults.sourceNumAll;
                        app.SourceNum = ComputingResults.SourceNum;
                        app.SourceIdxAll = ComputingResults.SourceIdxAll;
                        app.rawSourceNum = ComputingResults.SourceNum;
                        app.rawSourceIdxAll = ComputingResults.SourceIdxAll;                       

                        app.path = ComputingResults.IRpath;
                        app.file = ComputingResults.IRfile;

                        str = sprintf("已成功导入\n%s", app.file);
                        app.IRNameLabel.Text = str;
                        app.StartComputeButton.Enable = 'on';
                        
                        app.initAmbi = ComputingResults.initAmbi;
                        app.initPowermap = ComputingResults.initPowermap;
                        app.initIRIS = ComputingResults.initIRIS;
                        app.initSourceLoc = ComputingResults.initSourceLoc;

                        app.SRIR = ComputingResults.SRIR;

                        app.PowermapAzi = ComputingResults.PowermapAzi;
                        app.PowermapEle = ComputingResults.PowermapEle;
                        app.srpCum_dB = ComputingResults.srpCum_dB;

                        app.conf = ComputingResults.conf;
                        app.sndHedgehog = ComputingResults.sndHedgehog;
                        app.wxyz = ComputingResults.wxyz;

                        app.initSourceLoc = ComputingResults.initSourceLoc;
                        app.srcPos = ComputingResults.srcPos;

                        app.AmbiSourceIDDropDown.Items = sprintfc('%d', app.SourceIdxAll);
                        app.PowermapSourceIDDropDown.Items = sprintfc('%d', app.SourceIdxAll);
                        app.IRISSourceIDDropDown.Items = sprintfc('%d', app.SourceIdxAll);
                        app.SourceLocSourceIDDropDown.Items = sprintfc('%d', app.SourceIdxAll);

                        srcIdx = find(str2num(app.SourceLocSourceIDDropDown.Value) == app.SourceIdxAll);
                        app.SourcePositionsLabel.Text = strcat("声源位置（m）：", num2str(round(app.srcPos(:,srcIdx).', 2)));

                        %% UI settings
                        cla(app.AmbiPlotUIAxes);
                        cla(app.PowermapPlotUIAxes);
                        cla(app.IRISPlotUIAxes1);
                        cla(app.SourceLocPlotUIAxes);

                        app.AmbiSourceIDDropDown.Enable = 'on';
                        app.Ambi_n_Spinner.Enable = 'on';
                        app.AmbiPlotButton.Enable = 'on';
                        app.AmbiExportButton.Enable = 'on';

                        app.PowermapSourceIDDropDown.Enable = 'on';
                        app.PowermapAziSlider.Enable = 'on';
                        app.PowermapEleSlider.Enable = 'on';
                        app.PowermapPlotButton.Enable = 'on';
                        app.PowermapExportButton.Enable = 'on';

                        app.IRISSourceIDDropDown.Enable = 'on';
                        app.IRISAziSlider.Enable = 'on';
                        app.IRISEleSlider.Enable = 'on';
                        app.IRISPlotButton.Enable = 'on';
                        app.IRISExportButton.Enable = 'on';

                        app.SourceLocSourceIDDropDown.Enable = 'on';
                        app.SourceLocAziSlider.Enable = 'on';
                        app.SourceLocEleSlider.Enable = 'on';
                        app.SourceLocPlotButton.Enable = 'on';
                        app.SourceLocExportButton.Enable = 'on';

                        app.SaveComputeButton.Enable = 'off';
                        
                        if ~isempty(app.sourceIDNodes)
                            for i = 1:length(app.sourceIDNodes)
                                app.sourceIDNodes{i}.delete;
                            end
                        end
                        for i = 1:app.sourceNumAll
                            app.sourceIDNodes{i} = uitreenode(app.ChooseSourceIDNode, 'Text', ['声源 ' num2str(i)], 'NodeData', i);
                        end
                        
                        app.ChooseSourceIDNode.expand;
                        
                        if app.SourceNum == app.sourceNumAll
                            Nodes = [app.ChooseSourceIDNode app.sourceIDNodes{:}];
                        else
                            Nodes = [];
                            for i = 1:app.sourceNumAll
                                if any(app.sourceIDNodes{i}.NodeData == app.SourceIdxAll)
                                    Nodes = [Nodes app.sourceIDNodes{i}];
                                end
                            end
                        end
                        app.Tree.SelectedNodes = Nodes;
                        
                        app.loadIRFlag = false;
                        app.loadComputeFlag = true;

                        close(d);
                        pause(0.2);
                        uialert(app.UIFigure, '已全部导入！', '成功', 'Icon', 'success');
                    else
                        uialert(app.UIFigure, '未导入正确文件！', '错误');
                    end
                end
            end
        end

        % Value changed function: AmbiSourceIDDropDown
        function AmbiSourceIDDropDownValueChanged(app, event)
            app.AmbiPlotButton.Enable = 'on';
        end

        % Value changed function: PowermapSourceIDDropDown
        function PowermapSourceIDDropDownValueChanged(app, event)
            app.PowermapPlotButton.Enable = 'on';
        end

        % Value changed function: IRISSourceIDDropDown
        function IRISSourceIDDropDownValueChanged(app, event)
            app.IRISPlotButton.Enable = 'on';
        end

        % Value changed function: SourceLocSourceIDDropDown
        function SourceLocSourceIDDropDownValueChanged(app, event)
            value = app.SourceLocSourceIDDropDown.Value;
            srcIdx = find(str2num(value) == app.SourceIdxAll);
            app.SourcePositionsLabel.Text = strcat("声源位置（m）：", num2str(round(app.srcPos(:,srcIdx).', 2)));
%             app.SourceLocSourceIDDropDown.Enable = 'on';
        end

        % Value changed function: MicArraySelectionDropDown
        function MicArraySelectionDropDownValueChanged(app, event)
            app.micArrayType = app.MicArraySelectionDropDown.Value;
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            switch selectedTab.Title
                case app.AmbiTab.Title
                    disableDefaultInteractivity(app.AmbiPlotUIAxes);
                    h1 = rotate3d(app.AmbiPlotUIAxes);
                    h1.Enable = 'off';
                case app.PowermapTab.Title
                    disableDefaultInteractivity(app.PowermapPlotUIAxes);
                    h2 = rotate3d(app.PowermapPlotUIAxes);
                    h2.ActionPostCallback = @app.myPowermapPostCallBack;
                    h2.Enable = 'on';
                case app.IRISTab.Title
                    disableDefaultInteractivity(app.IRISPlotUIAxes1);
                    h31 = rotate3d(app.IRISPlotUIAxes1);
                    h31.ActionPostCallback = @app.myIRISPostCallBack;
                    h31.Enable = 'on';
                case app.SourceLocTab.Title
                    disableDefaultInteractivity(app.SourceLocPlotUIAxes);
                    h4 = rotate3d(app.SourceLocPlotUIAxes);
                    h4.ActionPostCallback = @app.mySourceLocPostCallBack;
                    h4.Enable = 'on';
            end
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            selectedNodes = app.Tree.SelectedNodes;
            app.rawSourceNum = length(selectedNodes);
                    
            if selectedNodes(1).NodeData == 0
                app.Tree.SelectedNodes = [app.ChooseSourceIDNode app.sourceIDNodes{:}];
                app.rawSourceNum = app.sourceNumAll;
                app.rawSourceIdxAll = 1:app.sourceNumAll;
                
                app.initSourceLoc.refSpkIdx = app.sourceNumAll;
%                 app.initSourceLoc.rDistance = 2.297437311711041;
            else
                app.rawSourceIdxAll = zeros(1, app.rawSourceNum);
                if app.rawSourceNum ~= app.sourceNumAll
                    for i = 1:app.rawSourceNum
                        app.rawSourceIdxAll(i) = selectedNodes(i).NodeData;
                    end
                    
                    app.initSourceLoc.refSpkIdx = app.rawSourceIdxAll(end);
%                     RDistances = [3.962781494735332 3.546720934543785 2.174470922865283 3.385635889532285 3.466745418167680 2.297437311711041];
%                     app.initSourceLoc.rDistance = RDistances(app.initSourceLoc.refSpkIdx);
                elseif app.rawSourceNum == app.sourceNumAll
                    app.Tree.SelectedNodes = [app.ChooseSourceIDNode app.sourceIDNodes{:}];
                    app.rawSourceNum = app.sourceNumAll;
                    app.rawSourceIdxAll = 1:app.sourceNumAll;
                    
                    app.initSourceLoc.refSpkIdx = app.sourceNumAll;
%                     app.initSourceLoc.rDistance = 2.297437311711041;
                else
                    error("Unknown Error!")
                end
            end
            
            app.initSourceLoc.rDistance = 0;
            
%             disp(app.rawSourceNum);
%             disp(app.rawSourceIdxAll);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {720, 720};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {244, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LeftGridLayout = uigridlayout(app.LeftPanel);
            app.LoadIRPanel = uipanel(app.LeftGridLayout);
            app.LoadIRPanel.Title = ' 导入IR';
            app.IRPathLabel = uilabel(app.LoadIRPanel);
            app.IRPathLabel.Text = '  IR 文件 : ';
            app.IRNameLabel = uilabel(app.LoadIRPanel);
            app.IRNameLabel.Text = '';
            app.LoadIRButton = uibutton(app.LoadIRPanel, 'push');
            app.LoadIRButton.ButtonPushedFcn = createCallbackFcn(app, @LoadIRButtonPushed, true);
            app.LoadIRButton.Text = '浏览';
            app.ComputePanel = uipanel(app.LeftGridLayout);
            app.ComputePanel.Title = ' 运算';
            app.StartComputeButton = uibutton(app.ComputePanel, 'push');
            app.StartComputeButton.ButtonPushedFcn = createCallbackFcn(app, @StartComputeButtonPushed, true);
            app.StartComputeButton.Text = {'开始运算'; ''};
            app.SaveComputeButton = uibutton(app.ComputePanel, 'push');
            app.SaveComputeButton.ButtonPushedFcn = createCallbackFcn(app, @SaveComputeButtonPushed, true);
            app.SaveComputeButton.Text = '保存运算结果';
            app.LoadComputeButton = uibutton(app.ComputePanel, 'push');
            app.LoadComputeButton.ButtonPushedFcn = createCallbackFcn(app, @LoadComputeButtonPushed, true);
            app.LoadComputeButton.Text = '导入运算结果';
            app.initSettingPanel = uipanel(app.LeftGridLayout);
            app.initSettingPanel.Title = ' 设置';
            app.IRISSettingButton = uibutton(app.initSettingPanel, 'push');
            app.IRISSettingButton.ButtonPushedFcn = createCallbackFcn(app, @IRISSettingButtonPushed, true);
            app.IRISSettingButton.Text = 'IR方向图';
            app.SourceLocSettingButton = uibutton(app.initSettingPanel, 'push');
            app.SourceLocSettingButton.ButtonPushedFcn = createCallbackFcn(app, @SourceLocSettingButtonPushed, true);
            app.SourceLocSettingButton.Text = '声源定位';
            app.AmbiSettingButton = uibutton(app.initSettingPanel, 'push');
            app.AmbiSettingButton.ButtonPushedFcn = createCallbackFcn(app, @AmbiSettingButtonPushed, true);
            app.AmbiSettingButton.Text = '高阶Ambisonics';
            app.PowermapSettingButton = uibutton(app.initSettingPanel, 'push');
            app.PowermapSettingButton.ButtonPushedFcn = createCallbackFcn(app, @PowermapSettingButtonPushed, true);
            app.PowermapSettingButton.Text = '声能分布图';
            app.ChooseSourceIDLabel = uilabel(app.initSettingPanel);
            app.ChooseSourceIDLabel.Text = '  选择声源 ID : ';
            app.Label = uilabel(app.initSettingPanel);
            app.Label.Text = '  选择传声器阵列 : ';
            app.MicArraySelectionDropDown = uidropdown(app.initSettingPanel);
            app.MicArraySelectionDropDown.Items = {'ESMA', 'ESMA2'};
            app.MicArraySelectionDropDown.ValueChangedFcn = createCallbackFcn(app, @MicArraySelectionDropDownValueChanged, true);
            app.MicArraySelectionDropDown.Value = 'ESMA';
            app.Tree = uitree(app.initSettingPanel);
            app.Tree.Multiselect = 'on';
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.ChooseSourceIDNode = uitreenode(app.Tree);
            app.ChooseSourceIDNode.NodeData = 0;
            app.ChooseSourceIDNode.Text = '全部选择';
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.AmbiTab = uitab(app.TabGroup);
            app.AmbiTab.Title = '高阶Ambisonics';
            app.AmbiGridLayout = uigridlayout(app.AmbiTab);
            app.AmbiPlotSettingPanel = uipanel(app.AmbiGridLayout);
            app.AmbiPlotSettingPanel.Title = ' 绘图设置';
            app.nSpinnerLabel = uilabel(app.AmbiPlotSettingPanel);
            app.nSpinnerLabel.Text = 'n';
            app.Ambi_n_Spinner = uispinner(app.AmbiPlotSettingPanel);
            app.Ambi_n_Spinner.ValueChangedFcn = createCallbackFcn(app, @Ambi_n_SpinnerValueChanged, true);
            app.AmbiExportButton = uibutton(app.AmbiPlotSettingPanel, 'push');
            app.AmbiExportButton.ButtonPushedFcn = createCallbackFcn(app, @AmbiExportButtonPushed, true);
            app.AmbiExportButton.Text = '保存数据';
            app.mSpinnerLabel = uilabel(app.AmbiPlotSettingPanel);
            app.mSpinnerLabel.Text = 'm';
            app.Ambi_m_Spinner = uispinner(app.AmbiPlotSettingPanel);
            app.Ambi_m_Spinner.ValueChangedFcn = createCallbackFcn(app, @Ambi_m_SpinnerValueChanged, true);
            app.AmbiPlotButton = uibutton(app.AmbiPlotSettingPanel, 'push');
            app.AmbiPlotButton.ButtonPushedFcn = createCallbackFcn(app, @AmbiPlotButtonPushed, true);
            app.AmbiPlotButton.Text = '绘图';
            app.IDDropDownLabel = uilabel(app.AmbiPlotSettingPanel);
            app.IDDropDownLabel.Text = '声源 ID';
            app.AmbiSourceIDDropDown = uidropdown(app.AmbiPlotSettingPanel);
            app.AmbiSourceIDDropDown.Items = {'1', '2', '3', '4', '5', '6'};
            app.AmbiSourceIDDropDown.ValueChangedFcn = createCallbackFcn(app, @AmbiSourceIDDropDownValueChanged, true);
            app.AmbiSourceIDDropDown.Value = '1';
            app.AmbiPlotUIAxes = uiaxes(app.AmbiGridLayout);
            title(app.AmbiPlotUIAxes, 'Title')
            xlabel(app.AmbiPlotUIAxes, 'X')
            ylabel(app.AmbiPlotUIAxes, 'Y')
            zlabel(app.AmbiPlotUIAxes, 'Z')
            app.PowermapTab = uitab(app.TabGroup);
            app.PowermapTab.Title = '声能分布图';
            app.PowermapGridLayout = uigridlayout(app.PowermapTab);
            app.PowermapPlotSettingPanel = uipanel(app.PowermapGridLayout);
            app.PowermapPlotSettingPanel.Title = ' 绘图设置';
            app.PowermapExportButton = uibutton(app.PowermapPlotSettingPanel, 'push');
            app.PowermapExportButton.ButtonPushedFcn = createCallbackFcn(app, @PowermapExportButtonPushed, true);
            app.PowermapExportButton.Text = '保存数据';
            app.PowermapPlotButton = uibutton(app.PowermapPlotSettingPanel, 'push');
            app.PowermapPlotButton.ButtonPushedFcn = createCallbackFcn(app, @PowermapPlotButtonPushed, true);
            app.PowermapPlotButton.Text = '绘图';
            app.Slider_4Label = uilabel(app.PowermapPlotSettingPanel);
            app.Slider_4Label.Text = '方位角';
            app.PowermapAziSlider = uislider(app.PowermapPlotSettingPanel);
            app.PowermapAziSlider.Limits = [-180 180];
            app.PowermapAziSlider.ValueChangingFcn = createCallbackFcn(app, @PowermapAziSliderValueChanging, true);
            app.Slider_4eleLabel = uilabel(app.PowermapPlotSettingPanel);
            app.Slider_4eleLabel.Text = '俯仰角';
            app.PowermapEleSlider = uislider(app.PowermapPlotSettingPanel);
            app.PowermapEleSlider.Limits = [-90 90];
            app.PowermapEleSlider.ValueChangingFcn = createCallbackFcn(app, @PowermapEleSliderValueChanging, true);
            app.PowermapEleSlider.Value = 90;
            app.IDDropDownLabel_2 = uilabel(app.PowermapPlotSettingPanel);
            app.IDDropDownLabel_2.Text = '声源 ID';
            app.PowermapSourceIDDropDown = uidropdown(app.PowermapPlotSettingPanel);
            app.PowermapSourceIDDropDown.Items = {'1', '2', '3', '4', '5', '6'};
            app.PowermapSourceIDDropDown.ValueChangedFcn = createCallbackFcn(app, @PowermapSourceIDDropDownValueChanged, true);
            app.PowermapSourceIDDropDown.Value = '1';
            app.PowermapPlotUIAxes = uiaxes(app.PowermapGridLayout);
            title(app.PowermapPlotUIAxes, '声能分布图')
            xlabel(app.PowermapPlotUIAxes, 'X')
            ylabel(app.PowermapPlotUIAxes, 'Y')
            zlabel(app.PowermapPlotUIAxes, 'Z')
            app.IRISTab = uitab(app.TabGroup);
            app.IRISTab.Title = 'IR方向图';
            app.IRISGridLayout = uigridlayout(app.IRISTab);
            app.IRISPlotSettingPanel = uipanel(app.IRISGridLayout);
            app.IRISPlotSettingPanel.Title = ' 绘图设置';
            app.Slider_3Label = uilabel(app.IRISPlotSettingPanel);
            app.Slider_3Label.Text = '方位角';
            app.IRISAziSlider = uislider(app.IRISPlotSettingPanel);
            app.IRISAziSlider.Limits = [-180 180];
            app.IRISAziSlider.ValueChangingFcn = createCallbackFcn(app, @IRISAziSliderValueChanging, true);
            app.IRISAziSlider.Value = 30;
            app.Slider_3eleLabel = uilabel(app.IRISPlotSettingPanel);
            app.Slider_3eleLabel.Text = '俯仰角';
            app.IRISEleSlider = uislider(app.IRISPlotSettingPanel);
            app.IRISEleSlider.Limits = [-90 90];
            app.IRISEleSlider.ValueChangingFcn = createCallbackFcn(app, @IRISEleSliderValueChanging, true);
            app.IRISEleSlider.Value = 30;
            app.IRISExportButton = uibutton(app.IRISPlotSettingPanel, 'push');
            app.IRISExportButton.ButtonPushedFcn = createCallbackFcn(app, @IRISExportButtonPushed, true);
            app.IRISExportButton.Text = '保存数据';
            app.IRISPlotButton = uibutton(app.IRISPlotSettingPanel, 'push');
            app.IRISPlotButton.ButtonPushedFcn = createCallbackFcn(app, @IRISPlotButtonPushed, true);
            app.IRISPlotButton.Text = '绘图';
            app.IDDropDown_2Label = uilabel(app.IRISPlotSettingPanel);
            app.IDDropDown_2Label.Text = '声源 ID';
            app.IRISSourceIDDropDown = uidropdown(app.IRISPlotSettingPanel);
            app.IRISSourceIDDropDown.Items = {'1', '2', '3', '4', '5', '6'};
            app.IRISSourceIDDropDown.ValueChangedFcn = createCallbackFcn(app, @IRISSourceIDDropDownValueChanged, true);
            app.IRISSourceIDDropDown.Value = '1';
            app.IRISPlotUIAxes1 = uiaxes(app.IRISGridLayout);
            title(app.IRISPlotUIAxes1, 'IR 方向图')
            xlabel(app.IRISPlotUIAxes1, 'X')
            ylabel(app.IRISPlotUIAxes1, 'Y')
            zlabel(app.IRISPlotUIAxes1, 'Z')
            app.SourceLocTab = uitab(app.TabGroup);
            app.SourceLocTab.Title = '声源定位';
            app.SourceLocGridLayout = uigridlayout(app.SourceLocTab);
            app.SourceLocPlotSettingPanel = uipanel(app.SourceLocGridLayout);
            app.SourceLocPlotSettingPanel.Title = ' 绘图设置';
            app.SliderLabel = uilabel(app.SourceLocPlotSettingPanel);
            app.SliderLabel.Text = '方位角';
            app.SourceLocAziSlider = uislider(app.SourceLocPlotSettingPanel);
            app.SourceLocAziSlider.Limits = [-180 180];
            app.SourceLocAziSlider.ValueChangingFcn = createCallbackFcn(app, @SourceLocAziSliderValueChanging, true);
            app.SourceLocAziSlider.Value = 30;
            app.Slider_2Label = uilabel(app.SourceLocPlotSettingPanel);
            app.Slider_2Label.Text = '俯仰角';
            app.SourceLocEleSlider = uislider(app.SourceLocPlotSettingPanel);
            app.SourceLocEleSlider.Limits = [-90 90];
            app.SourceLocEleSlider.ValueChangingFcn = createCallbackFcn(app, @SourceLocEleSliderValueChanging, true);
            app.SourceLocEleSlider.Value = 30;
            app.SourcePositionsLabel = uilabel(app.SourceLocPlotSettingPanel);
            app.SourcePositionsLabel.Text = '声源位置（m）：';
            app.SourceLocExportButton = uibutton(app.SourceLocPlotSettingPanel, 'push');
            app.SourceLocExportButton.ButtonPushedFcn = createCallbackFcn(app, @SourceLocExportButtonPushed, true);
            app.SourceLocExportButton.Text = '保存数据';
            app.SourceLocPlotButton = uibutton(app.SourceLocPlotSettingPanel, 'push');
            app.SourceLocPlotButton.ButtonPushedFcn = createCallbackFcn(app, @SourceLocPlotButtonPushed, true);
            app.SourceLocPlotButton.Text = '绘图';
            app.IDDropDown_2Label_2 = uilabel(app.SourceLocPlotSettingPanel);
            app.IDDropDown_2Label_2.Text = '声源 ID';
            app.SourceLocSourceIDDropDown = uidropdown(app.SourceLocPlotSettingPanel);
            app.SourceLocSourceIDDropDown.Items = {'1', '2', '3', '4', '5', '6'};
            app.SourceLocSourceIDDropDown.ValueChangedFcn = createCallbackFcn(app, @SourceLocSourceIDDropDownValueChanged, true);
            app.SourceLocSourceIDDropDown.Value = '1';
            app.SourceLocPlotUIAxes = uiaxes(app.SourceLocGridLayout);
            title(app.SourceLocPlotUIAxes, 'Title')
            xlabel(app.SourceLocPlotUIAxes, 'X')
            ylabel(app.SourceLocPlotUIAxes, 'Y')
            zlabel(app.SourceLocPlotUIAxes, 'Z')
            app.Panel = uipanel(app.RightPanel);
            app.Panel.Title = 'Panel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MainUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end