classdef PowermapSettings < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        initPowermapSettingsPanel  matlab.ui.container.Panel
        AngleSettingPanel          matlab.ui.container.Panel
        Label                      matlab.ui.control.Label
        aziMinEditField            matlab.ui.control.NumericEditField
        Label_2                    matlab.ui.control.Label
        aziMaxEditField            matlab.ui.control.NumericEditField
        dBLabel                    matlab.ui.control.Label
        dAngleEditField            matlab.ui.control.NumericEditField
        Label_3                    matlab.ui.control.Label
        eleMinEditField            matlab.ui.control.NumericEditField
        Label_4                    matlab.ui.control.Label
        eleMaxEditField            matlab.ui.control.NumericEditField
        LPFilterSettingPanel       matlab.ui.container.Panel
        Label_5                    matlab.ui.control.Label
        Switch                     matlab.ui.control.Switch
        dBLabel_2                  matlab.ui.control.Label
        CutoffFreqEditField        matlab.ui.control.NumericEditField
        dBLabel_3                  matlab.ui.control.Label
        FilterOrderEditField       matlab.ui.control.NumericEditField
        AscertainButton            matlab.ui.control.Button
        CancelButton               matlab.ui.control.Button
    end

    
    properties (Access = private)
        CallingApp
        lowPassFilterParams = struct;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, initPowermap)
            app.CallingApp = mainapp;
                  
            app.dAngleEditField.Value = initPowermap.dAngle;
            app.aziMinEditField.Value = initPowermap.aziMin;
            app.aziMaxEditField.Value = initPowermap.aziMax;
            app.eleMinEditField.Value = initPowermap.eleMin;
            app.eleMaxEditField.Value = initPowermap.eleMax;
            app.lowPassFilterParams.status = initPowermap.lowPassFilterParams.status;
            app.CutoffFreqEditField.Value = initPowermap.lowPassFilterParams.maxFreq;
            app.FilterOrderEditField.Value = initPowermap.lowPassFilterParams.filterOrder;
            
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            if strcmp(value, 'On')
                app.lowPassFilterParams.status = 'on';
                app.FilterOrderEditField.Editable = 'on';
                app.CutoffFreqEditField.Editable = 'on';
            else
                app.lowPassFilterParams.status = 'off';
                app.FilterOrderEditField.Editable = 'off';
                app.CutoffFreqEditField.Editable = 'off';
            end

        end

        % Button pushed function: AscertainButton
        function AscertainButtonPushed(app, event)
            d = uiprogressdlg(app.UIFigure, 'Title', '正在进行初始化设置', ...
                    'Message', '请您耐心等待', 'Indeterminate', 'on');
            
            app.CallingApp.initPowermap.dAngle = app.dAngleEditField.Value;
            app.CallingApp.initPowermap.aziMin = app.aziMinEditField.Value;
            app.CallingApp.initPowermap.aziMax = app.aziMaxEditField.Value;
            app.CallingApp.initPowermap.eleMin = app.eleMinEditField.Value;
            app.CallingApp.initPowermap.eleMax = app.eleMaxEditField.Value;

            app.lowPassFilterParams.maxFreq = app.CutoffFreqEditField.Value;
            app.lowPassFilterParams.filterOrder = app.FilterOrderEditField.Value;
            app.CallingApp.initPowermap.lowPassFilterParams = app.lowPassFilterParams;

            app.CallingApp.PowermapSourceIDDropDown.Enable = 'off';
            app.CallingApp.PowermapAziSlider.Enable = 'off';
            app.CallingApp.PowermapEleSlider.Enable = 'off';
            app.CallingApp.PowermapPlotButton.Enable = 'off';
            app.CallingApp.PowermapExportButton.Enable = 'off';
            cla(app.CallingApp.PowermapPlotUIAxes);
            
            pause(.3)
            close(d);

            uialert(app.CallingApp.UIFigure, '已完成 < 声能分布图 > 初始化设置！', '成功', 'Icon', 'success');

            app.CallingApp.PowermapSettingButton.Enable = 'on';
            delete(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.CallingApp.PowermapSettingButton.Enable = 'on';
            delete(app)
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.CallingApp.PowermapSettingButton.Enable = 'on';
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.initPowermapSettingsPanel = uipanel(app.UIFigure);
            app.initPowermapSettingsPanel.Title = '声能分布图初始化设置';
            app.AngleSettingPanel = uipanel(app.initPowermapSettingsPanel);
            app.AngleSettingPanel.Title = ' 角度设置';
            app.Label = uilabel(app.initPowermapSettingsPanel);
            app.Label.Text = '方位角范围（°）：';
            app.aziMinEditField = uieditfield(app.initPowermapSettingsPanel, 'numeric');
            app.aziMinEditField.Value = -180;
            app.Label_2 = uilabel(app.initPowermapSettingsPanel);
            app.Label_2.Text = '至';
            app.aziMaxEditField = uieditfield(app.initPowermapSettingsPanel, 'numeric');
            app.aziMaxEditField.Value = 180;
            app.dBLabel = uilabel(app.initPowermapSettingsPanel);
            app.dBLabel.Text = '角度间距（°）：';
            app.dAngleEditField = uieditfield(app.initPowermapSettingsPanel, 'numeric');
            app.dAngleEditField.Limits = [0 Inf];
            app.dAngleEditField.Value = 1;
            app.Label_3 = uilabel(app.initPowermapSettingsPanel);
            app.Label_3.Text = '俯仰角范围（°）：';
            app.eleMinEditField = uieditfield(app.initPowermapSettingsPanel, 'numeric');
            app.eleMinEditField.Value = -90;
            app.Label_4 = uilabel(app.initPowermapSettingsPanel);
            app.Label_4.Text = '至';
            app.eleMaxEditField = uieditfield(app.initPowermapSettingsPanel, 'numeric');
            app.eleMaxEditField.Value = 90;
            app.LPFilterSettingPanel = uipanel(app.initPowermapSettingsPanel);
            app.LPFilterSettingPanel.Title = ' 低通滤波器相关设置';
            app.Label_5 = uilabel(app.LPFilterSettingPanel);
            app.Label_5.Text = {'是否开启'; '低通滤波器'};
            app.Switch = uiswitch(app.LPFilterSettingPanel, 'slider');
            app.Switch.Items = {'关', '开'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = '开';
            app.dBLabel_2 = uilabel(app.LPFilterSettingPanel);
            app.dBLabel_2.Text = '截止频率（Hz）：';
            app.CutoffFreqEditField = uieditfield(app.LPFilterSettingPanel, 'numeric');
            app.CutoffFreqEditField.Value = 10000;
            app.dBLabel_3 = uilabel(app.LPFilterSettingPanel);
            app.dBLabel_3.Text = '滤波器阶数：';
            app.FilterOrderEditField = uieditfield(app.LPFilterSettingPanel, 'numeric');
            app.FilterOrderEditField.Value = 50;
            app.AscertainButton = uibutton(app.initPowermapSettingsPanel, 'push');
            app.AscertainButton.ButtonPushedFcn = createCallbackFcn(app, @AscertainButtonPushed, true);
            app.AscertainButton.Text = {'确定'; ''};
            app.CancelButton = uibutton(app.initPowermapSettingsPanel, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = '取消';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PowermapSettings(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end