classdef SourceLocSettings < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        Panel                matlab.ui.container.Panel
        UITable              matlab.ui.control.Table
        AscertainButton      matlab.ui.control.Button
        CancelButton         matlab.ui.control.Button
        Label                matlab.ui.control.Label
        refMicIndexSpinner   matlab.ui.control.Spinner
        Label_3              matlab.ui.control.Label
        DistanceEditField    matlab.ui.control.NumericEditField
        Label_4              matlab.ui.control.Label
        refSpkIndexDropDown  matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        CallingApp
        spksPosReal
        rDistances
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, refSpkIdxAll, initSourceLoc)
            app.CallingApp = mainapp;
            app.UITable.RowName = {'ESMA坐标'};
            app.UITable.Data = initSourceLoc.ESMAPos;
            
            app.refMicIndexSpinner.Value = initSourceLoc.refMicIdx;

            app.refSpkIndexDropDown.Items = sprintfc('%d', refSpkIdxAll);
            app.refSpkIndexDropDown.Value = sprintfc('%d', initSourceLoc.refSpkIdx);
            
            app.DistanceEditField.Value = initSourceLoc.rDistance;
            
%             app.UITable.RowName = {'ESMA坐标';'参考扬声器坐标'};
%             app.UITable.Data = [0 0 1.2;0.98 -1.8 0.1];
%             app.spksPosReal = [2.94 2.7 1.8;2.94 1.8 0.1;1.96 0 0.1;2.94 -1.8 1.6;1.96 -2.7 0.1;0.98 -1.8 0.1];
%             app.rDistances = [3.962781494735332 3.546720934543785 2.174470922865283 3.385635889532285 3.466745418167680 2.297437311711041];
%             app.DistanceEditField.Value = app.rDistances(str2num(app.refSpkIndexDropDown.Value));

        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            app.UITable.Data(indices(1),indices(2)) = newData;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.CallingApp.SourceLocSettingButton.Enable = 'on';
            delete(app)
        end

        % Button pushed function: AscertainButton
        function AscertainButtonPushed(app, event)
            d = uiprogressdlg(app.UIFigure, 'Title', '正在进行初始化设置', ...
                    'Message', '请您耐心等待', 'Indeterminate', 'on');

            app.CallingApp.initSourceLoc.ESMAPos = app.UITable.Data(1,:);
            app.CallingApp.initSourceLoc.refMicIdx = app.refMicIndexSpinner.Value;
            app.CallingApp.initSourceLoc.refSpkIdx = str2num(app.refSpkIndexDropDown.Value);
            app.CallingApp.initSourceLoc.rDistance = app.DistanceEditField.Value;
%             app.CallingApp.initSourceLoc.refSpkPosReal = app.UITable.Data(2,:);

            app.CallingApp.SourceLocSourceIDDropDown.Enable = 'off';
            app.CallingApp.SourceLocAziSlider.Enable = 'off';
            app.CallingApp.SourceLocEleSlider.Enable = 'off';
            app.CallingApp.SourceLocPlotButton.Enable = 'off';
            app.CallingApp.SourceLocExportButton.Enable = 'off';
            cla(app.CallingApp.SourceLocPlotUIAxes);

            pause(.3)
            close(d);

            uialert(app.CallingApp.UIFigure, '已完成 < 声源定位 > 初始化设置！', '成功', 'Icon', 'success');

            app.CallingApp.SourceLocSettingButton.Enable = 'on';
            delete(app)
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.CallingApp.SourceLocSettingButton.Enable = 'on';
            delete(app)
        end

        % Value changed function: refSpkIndexDropDown
        function refSpkIndexDropDownValueChanged(app, event)
            app.DistanceEditField.Value = app.rDistances(str2num(app.refSpkIndexDropDown.Value));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '声源定位初始化设置';
            app.UITable = uitable(app.Panel);
            app.UITable.ColumnName = {'x（m）'; 'y（m）'; 'z（m）'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.AscertainButton = uibutton(app.Panel, 'push');
            app.AscertainButton.ButtonPushedFcn = createCallbackFcn(app, @AscertainButtonPushed, true);
            app.AscertainButton.Text = '确定';
            app.CancelButton = uibutton(app.Panel, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = '取消';
            app.Label = uilabel(app.Panel);
            app.Label.Text = '参考传声器标号：';
            app.refMicIndexSpinner = uispinner(app.Panel);
            app.refMicIndexSpinner.Limits = [1 16];
            app.refMicIndexSpinner.Value = 5;
            app.Label_3 = uilabel(app.Panel);
            app.Label_3.Text = '参考传声器与参考扬声器之间的距离（m）：';
            app.DistanceEditField = uieditfield(app.Panel, 'numeric');
            app.DistanceEditField.Limits = [0 Inf];
            app.Label_4 = uilabel(app.Panel);
            app.Label_4.Text = '参考扬声器标号：';
            app.refSpkIndexDropDown = uidropdown(app.Panel);
            app.refSpkIndexDropDown.Items = {'1', '2', '3', '4', '5', '6'};
            app.refSpkIndexDropDown.ValueChangedFcn = createCallbackFcn(app, @refSpkIndexDropDownValueChanged, true);
            app.refSpkIndexDropDown.Value = '6';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SourceLocSettings(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end