classdef wallFind < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        Panel             matlab.ui.container.Panel
        Button_2          matlab.ui.control.Button
        EditField         matlab.ui.control.EditField
        Panel_2           matlab.ui.container.Panel
        EditField_3Label  matlab.ui.control.Label
        EditField_3       matlab.ui.control.NumericEditField
        Button_3          matlab.ui.control.Button
        Panel_3           matlab.ui.container.Panel
        EditField_2Label  matlab.ui.control.Label
        EditField_2       matlab.ui.control.EditField
        UIAxes            matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
         file % Description
    end
    
    methods (Access = private)
               
        function [Angule,Distance,Quality] = importfile(~,filename, startRow, endRow)
%IMPORTFILE 将文本文件中的数值数据作为列向量导入。
%   [ANGULE,DISTANC,EQUALITY] = IMPORTFILE(FILENAME)
%   读取文本文件 FILENAME 中默认选定范围的数据。
%
%   [ANGULE,DISTANC,EQUALITY] = IMPORTFILE(FILENAME, STARTROW, ENDROW)
%   读取文本文件 FILENAME 的 STARTROW 行到 ENDROW 行中的数据。
%
% Example:
%   [Angule,Distanc,eQuality] = importfile('gl120.txt',4, 862);
%
%    另请参阅 TEXTSCAN。

% 由 MATLAB 自动生成于 2022/05/13 15:41:19

%% 初始化变量。
if nargin<=2
    startRow = 4;
    endRow = inf;
end

%% 每个文本行的格式:
%   列1: 双精度值 (%f)
%	列2: 双精度值 (%f)
%   列3: 双精度值 (%f)
% 有关详细信息，请参阅 TEXTSCAN 文档。
formatSpec = '%f%f%f%[^\n\r]';

%% 打开文本文件。
fileID = fopen(filename,'r');

%% 根据格式读取数据列。
% 该调用基于生成此代码所用的文件的结构。如果其他文件出现错误，请尝试通过导入工具重新生成代码。
dataArray = textscan(fileID, formatSpec, endRow(1)-startRow(1)+1, 'Delimiter', '', 'WhiteSpace', '', 'TextType', 'string', 'EmptyValue', NaN, 'HeaderLines', startRow(1)-1, 'ReturnOnError', false, 'EndOfLine', '\r\n');
for block=2:length(startRow)
    frewind(fileID);
    dataArrayBlock = textscan(fileID, formatSpec, endRow(block)-startRow(block)+1, 'Delimiter', '', 'WhiteSpace', '', 'TextType', 'string', 'EmptyValue', NaN, 'HeaderLines', startRow(block)-1, 'ReturnOnError', false, 'EndOfLine', '\r\n');
    for col=1:length(dataArray)
        dataArray{col} = [dataArray{col};dataArrayBlock{col}];
    end
end

%% 关闭文本文件。
fclose(fileID);

%% 对无法导入的数据进行的后处理。
% 在导入过程中未应用无法导入的数据的规则，因此不包括后处理代码。要生成适用于无法导入的数据的代码，请在文件中选择无法导入的元胞，然后重新生成脚本。

%% 将导入的数组分配给列变量名称
Angule = dataArray{:, 1};
Distance = dataArray{:, 2};
Quality = dataArray{:, 3};
      
        end
        
        
        function [X Y]= node( ~,X1,Y1,X2,Y2 )

if X1(1)==Y1(1)
   X=X1(1);
   k2=(Y2(2)-X2(2))/(Y2(1)-X2(1));
   b2=X2(2)-k2*X2(1); 
   Y=k2*X+b2;
end
if X2(1)==Y2(1)
   X=X2(1);
   k1=(Y1(2)-X1(2))/(Y1(1)-X1(1));
   b1=X1(2)-k1*X1(1);
   Y=k1*X+b1;
end
if X1(1)~=Y1(1)&&X2(1)~=Y2(1)
   k1=(Y1(2)-X1(2))/(Y1(1)-X1(1));
   k2=(Y2(2)-X2(2))/(Y2(1)-X2(1));
   b1=X1(2)-k1*X1(1);
   b2=X2(2)-k2*X2(1);
    if k1==k2
      X=[];
      Y=[];
    else
   X=(b2-b1)/(k1-k2);
   Y=k1*X+b1;
   end
end
   
        end
        
        function vertices = wallFinder(app,lindarFileName, wallNum)
% lindarFileName：激光雷达导出文件路径
% wallNum：多边形墙壁数量
% vertices：多边形顶点坐标，matrix size == [2,wallNum]
%% load data
% lindarFileName = 'gl120.txt';
[Angule,Distance] = importfile(app,lindarFileName);

x = Distance .* sin(Angule * pi / 180) / 1e3;
y = Distance .* cos(Angule * pi / 180) / 1e3;
pos = [x,y].';
%% get vertices
xlimit = [min(x)-1,max(x)+1];
ylimit = [min(y)-1,max(y)+1];
%确定像素分辨率
pixSize = 0.1;
% 计算总的行列号
row = ceil((ylimit(2) - ylimit(1))/pixSize);
col = ceil((xlimit(2) - xlimit(1))/pixSize);
% 预设一张纯黑图像
img = uint8(zeros(row, col));
%  获取点的个数
[r,~] = size(pos');
%遍历所有点
for i= 1:1:r
x0 = pos(1,i);
y0 = pos(2,i);
rID = floor((y0 - ylimit(1))/pixSize)+1;%计算当前点对应的行号
cID = floor((x0 - xlimit(1))/pixSize)+1;%计算当前点对应的列号
img(rID,cID)= 255;%改变图像对应位置的像素值
end
%hough变换
[ H, theta, rho ] =hough(img);
%寻找交点
numpeaks=wallNum;
peaks=houghpeaks(H,numpeaks);
%根据交点画线
lines = houghlines(img,theta,rho,peaks);
%导出lines结构体数组内的端点，并画图
k = 1 : length(lines) 
xy1 = [lines(k).point1];
xy2 = [lines(k).point2];
A=reshape(xy1,2,length(lines));
B=reshape(xy2,2,length(lines));
X=[A(1,:); B(1,:)];
Y=[A(2,:); B(2,:)];
line(X,Y);
%求各个点到其他线段最短距离
for k = 1:length(lines)
    for m=1:length(lines)
        if m~=k
       d1(m,k) = abs(det([B(:,k)'-A(:,k)';A(:,m)'-A(:,k)']))/norm(B(:,k)'-A(:,k)');
       d2(m,k)= abs(det([B(:,k)'-A(:,k)';B(:,m)'-A(:,k)']))/norm(B(:,k)'-A(:,k)');
        end
    end
end
d1(logical(eye(size(d1))))=inf;
d2(logical(eye(size(d2))))=inf;
for i = 1 : size(d1, 1)
[d1_min(i),in] = min(d1(i, :)); 
[a,b] = ind2sub(size(d1),in);
L(i)=a;
end
for i = 1 : size(d2, 1)
[d2_min(i),in] = min(d2(i, :)); 
[c,d] = ind2sub(size(d2),in);
F(i)=c;
end
%求交点
for k = 1:length(lines)
    n=L(:,k);
    [x_intersect1(k) y_intersect1(k)]=node(app,A(:,k)',B(:,k)',A(:,n)',B(:,n)');
end
for k = 1:length(lines)
    n=F(:,k);
    [x_intersect2(k) y_intersect2(k)]=node(app,A(:,k)',B(:,k)',A(:,n)',B(:,n)');
end
XY1=[x_intersect1' y_intersect1'];
XY2=[x_intersect2' y_intersect2'];
XY=[XY1;XY2];
XY=unique(XY,'row','stable');
%坐标转化
rID1 = floor((0 - ylimit(1))/pixSize)+1;
cID1 = floor((0 - xlimit(1))/pixSize)+1;
Y_transform=(XY(:,2)-rID1).*pixSize;
X_transform=(XY(:,1)-cID1).*pixSize;
%顺时针定义坐标顺序
cx = mean(X_transform);
cy = mean(Y_transform);
a = atan2(Y_transform - cy, X_transform - cx);
[~, order] = sort(a, 'descend');
x_1 = X_transform(order);
y_1 = Y_transform(order);
vertices=[x_1 y_1].';

%% check output
assert(all(size(vertices,[1 2])==[2,wallNum]));
    end
    end        
   

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function ButtonValueChanged(app, event)
            
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            [filename,pathname]=uigetfile({'*.txt'},"输入点云数据");
            tf1=isequal(filename,0);
            tf2=isequal(pathname,0);
            app.file=strcat(pathname,filename);
            if tf1==0&&tf2==0
                lindarFileName=app.file;
                [Angule,Distance] = importfile(app,lindarFileName);
                x = Distance .* sin(Angule * pi / 180) / 1e3;
                y = Distance .* cos(Angule * pi / 180) / 1e3;
                plot(app.UIAxes,x,y,'o');
                app.EditField.Value="Load Successfully";
            end      
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            steps=100;
            hwait=waitbar(0,'请等待>>>>>>>>');
            for k=1:steps
                if steps-k<=5
                    waitbar(k/steps,hwait,'即将完成'); %因为k/steps在逐步增大，所有进度条在往右走，另外，如果这个hwait不加上的话，会显示n多个进度条窗口，让你关闭不过来啊，加上之后就只显示一个了
                    pause(0.05); %延时一下啊 否则太快看不出来变化，一般有个0.01就能看出变化，但还是比较快的
                else
                    str=['正在运行中',num2str(k),'%']; %用于显示如下图中"正在运行中46%"等比例数据
                    waitbar(k/steps,hwait,str);
                pause(0.05); %pause(n)函数是程序停止n秒后继续，n可以使分数或小数。函数的分辨率根据平台而定，大多数平台都能满足0.01秒的精度。
                end
            end
            close(hwait); % 注意必须添加close函数，也就是说运行完成后让此进度条消失
            if app.EditField_3.Value==0
            wallNum=4;
            else
            wallNum=app.EditField_3.Value;
            end
            lindarFileName=app.file;
            vertices=wallFinder(app,lindarFileName, wallNum);
            vertices=[vertices vertices(:,1)];
          app.EditField_2.Value =  mat2str(wallFinder(app,lindarFileName, wallNum));
             [Angule,Distance] = importfile(app,lindarFileName);
              x = Distance .* sin(Angule * pi / 180) / 1e3;
              y = Distance .* cos(Angule * pi / 180) / 1e3;
            hold(app.UIAxes,"on");
            plot(app.UIAxes,vertices(1,:),vertices(2,:),'r');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '导入点云文件';
            app.Button_2 = uibutton(app.Panel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '浏览';
            app.EditField = uieditfield(app.Panel, 'text');
            app.Panel_2 = uipanel(app.UIFigure);
            app.Panel_2.Title = '初始化设置';
            app.EditField_3Label = uilabel(app.Panel_2);
            app.EditField_3Label.Text = '多边形边数';
            app.EditField_3 = uieditfield(app.Panel_2, 'numeric');
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '开始运行';
            app.Panel_3 = uipanel(app.UIFigure);
            app.Panel_3.Title = '输出结果';
            app.EditField_2Label = uilabel(app.Panel_3);
            app.EditField_2Label.Text = '多边形顶点';
            app.EditField_2 = uieditfield(app.Panel_3, 'text');
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '点云图像')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = wallFind

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end