classdef wallFind1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        Panel             matlab.ui.container.Panel
        Button_2          matlab.ui.control.Button
        EditField         matlab.ui.control.EditField
        Panel_2           matlab.ui.container.Panel
        EditField_3Label  matlab.ui.control.Label
        EditField_3       matlab.ui.control.NumericEditField
        Button_3          matlab.ui.control.Button
        Panel_3           matlab.ui.container.Panel
        EditField_2Label  matlab.ui.control.Label
        EditField_2       matlab.ui.control.EditField
        UIAxes            matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        file % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            [filename,pathname]=uigetfile({'*.txt'},"输入点云数据");
            figure(app.UIFigure);
            tf1=isequal(filename,0);
            tf2=isequal(pathname,0);
            app.file=strcat(pathname,filename);
            if tf1==0&&tf2==0
                lindarFileName=app.file;
                [Angule,Distance] = importfile(lindarFileName);
                x = Distance .* sin(Angule * pi / 180) / 1e3;
                y = Distance .* cos(Angule * pi / 180) / 1e3;
                plot(app.UIAxes,x,y,'o');
                app.EditField.Value="Load Successfully";
            end
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
%             steps=100;
%             hwait=waitbar(0,'请等待>>>>>>>>');
%             for k=1:steps
%                 if steps-k<=5
%                     waitbar(k/steps,hwait,'即将完成'); %因为k/steps在逐步增大，所有进度条在往右走，另外，如果这个hwait不加上的话，会显示n多个进度条窗口，让你关闭不过来啊，加上之后就只显示一个了
%                     pause(0.05); %延时一下啊 否则太快看不出来变化，一般有个0.01就能看出变化，但还是比较快的
%                 else
%                     str=['正在运行中',num2str(k),'%']; %用于显示如下图中"正在运行中46%"等比例数据
%                     waitbar(k/steps,hwait,str);
%                 pause(0.05); %pause(n)函数是程序停止n秒后继续，n可以使分数或小数。函数的分辨率根据平台而定，大多数平台都能满足0.01秒的精度。
%                 end
%             end
%             close(hwait); % 注意必须添加close函数，也就是说运行完成后让此进度条消失
            d = uiprogressdlg(app.UIFigure,'Title','计算中','Indeterminate','on');
%             if app.EditField_3.Value==0
%             wallNum=4;
%             else
            wallNum=app.EditField_3.Value;
%             end
            lindarFileName=app.file;
            vertices=wallFinder1(lindarFileName, wallNum);
            vertices1=[vertices;vertices(1,:)].';
          app.EditField_2.Value =  mat2str(vertices);
            hold(app.UIAxes,"on");
            plot(app.UIAxes,vertices1(1,:),vertices1(2,:),'r');
            close(d);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '导入点云文件';
            app.Button_2 = uibutton(app.Panel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '浏览';
            app.EditField = uieditfield(app.Panel, 'text');
            app.Panel_2 = uipanel(app.UIFigure);
            app.Panel_2.Title = '初始化设置';
            app.EditField_3Label = uilabel(app.Panel_2);
            app.EditField_3Label.Text = '多边形边数';
            app.EditField_3 = uieditfield(app.Panel_2, 'numeric');
            app.EditField_3.Value = 4;
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '开始运行';
            app.Panel_3 = uipanel(app.UIFigure);
            app.Panel_3.Title = '输出结果';
            app.EditField_2Label = uilabel(app.Panel_3);
            app.EditField_2Label.Text = '多边形顶点';
            app.EditField_2 = uieditfield(app.Panel_3, 'text');
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '点云图像')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = wallFind1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end