classdef wallFind2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        RavenPanel        matlab.ui.container.Panel
        Button_6          matlab.ui.control.Button
        EditField_4       matlab.ui.control.NumericEditField
        Label             matlab.ui.control.Label
        Button_5          matlab.ui.control.Button
        Button_4          matlab.ui.control.Button
        Panel_3           matlab.ui.container.Panel
        EditField_2       matlab.ui.control.EditField
        Label_2           matlab.ui.control.Label
        Button_3          matlab.ui.control.Button
        Panel_2           matlab.ui.container.Panel
        EditField_3       matlab.ui.control.NumericEditField
        EditField_3Label  matlab.ui.control.Label
        Panel             matlab.ui.container.Panel
        EditField         matlab.ui.control.EditField
        Button_2          matlab.ui.control.Button
        UIAxes            matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        file % Description
        x % Description
        y
        vertices
        
    end


    methods (Access = private)
        
        function [x, y] = selectDataPoints(~, ax)
            roi = drawpolygon(ax,'Color','r','InteractionsAllowed' ,'none');
            x = roundn(roi.Position(:,1),-2);
            y = roundn(roi.Position(:,2),-2);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            set(app.RavenPanel,'enable','off');
            [filename,pathname]=uigetfile({'*.txt'},"输入点云数据");
            figure(app.UIFigure);
            tf1=isequal(filename,0);
            tf2=isequal(pathname,0);
            app.file=strcat(pathname,filename);
            if tf1==0&&tf2==0
                lindarFileName=app.file;
                [Angule,Distance] = importfile(lindarFileName);
                app.x = Distance .* sin(Angule * pi / 180) / 1e3;
                app.y = Distance .* cos(Angule * pi / 180) / 1e3;
                plot(app.UIAxes,app.x,app.y,'o');
                axis(app.UIAxes,'equal');
                app.EditField.Value="Load Successfully";
            end
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            %             steps=100;
            %             hwait=waitbar(0,'请等待>>>>>>>>');
            %             for k=1:steps
            %                 if steps-k<=5
            %                     waitbar(k/steps,hwait,'即将完成'); %因为k/steps在逐步增大，所有进度条在往右走，另外，如果这个hwait不加上的话，会显示n多个进度条窗口，让你关闭不过来啊，加上之后就只显示一个了
            %                     pause(0.05); %延时一下啊 否则太快看不出来变化，一般有个0.01就能看出变化，但还是比较快的
            %                 else
            %                     str=['正在运行中',num2str(k),'%']; %用于显示如下图中"正在运行中46%"等比例数据
            %                     waitbar(k/steps,hwait,str);
            %                 pause(0.05); %pause(n)函数是程序停止n秒后继续，n可以使分数或小数。函数的分辨率根据平台而定，大多数平台都能满足0.01秒的精度。
            %                 end
            %             end
            %             close(hwait); % 注意必须添加close函数，也就是说运行完成后让此进度条消失
            d = uiprogressdlg(app.UIFigure,'Title','计算中','Indeterminate','on');
            %             if app.EditField_3.Value==0
            %             wallNum=4;
            %             else
            wallNum=app.EditField_3.Value;
            %             end
            lindarFileName=app.file;
            app.vertices=wallFinder2(lindarFileName, wallNum);
            a=roundn(app.vertices,-2);
            vertices1=[app.vertices;app.vertices(1,:)].';
            app.EditField_2.Value =  mat2str(a);
            cla (app.UIAxes);
            plot(app.UIAxes,app.x,app.y,'o');
            hold(app.UIAxes,"on");
            plot(app.UIAxes,vertices1(1,:),vertices1(2,:),'r','LineWidth',2);
            hold(app.UIAxes,"off");
            close(d);
            set(app.RavenPanel,'enable','on');
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            plot(app.UIAxes,app.x,app.y,'o');
            %exportgraphics(app.UIAxes,'LinePlot.jpg');
            %imshow('LinePlot.jpg')
            %[a,b] = ginput();
            %app.EditField_2.Value =  mat2str([a,b]);
            [a, b] = selectDataPoints(app, app.UIAxes);
            app.vertices=[a, b];
            app.EditField_2.Value =  mat2str([a,b]);
            set(app.RavenPanel,'enable','on');
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            h=app.EditField_4.Value;
            projectName='room_shape.rpf';
            pathname='.\';
           [rpf, outputFilePath]=raven_setModelToFaces(app.vertices,h,pathname,projectName);
            rpf.plotModel;
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            h=app.EditField_4.Value;
            [projectName, pathname] = uiputfile('.rpf','保存为.rpf文件');
            tf1=isequal(projectName,0);
            tf2=isequal(pathname,0);
            if tf1==0&&tf2==0
               [rpf, outputFilePath]=raven_setModelToFaces(app.vertices,h,pathname,projectName);
               [newname, myDestination] = uiputfile('.ac','保存为.ac文件');
               tf1=isequal(newname,0);
               tf2=isequal(myDestination,0);
               if tf1==0&&tf2==0
                  copyfile([outputFilePath],[myDestination newname])
               end
            end
            %projectName='room_shape.rpf';
            %pathname='.\';
            %[rpf, outputFilePath]=raven_setModelToFaces(app.vertices,h,pathname,projectName);
            %[newname, myDestination] = uiputfile('.ac','保存为.ac文件');
            %tf1=isequal(newname,0);
            %tf2=isequal(myDestination,0);
            %if tf1==0&&tf2==0
             %  copyfile([outputFilePath],[myDestination newname])
            %end
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '点云图像')
            xlabel(app.UIAxes, 'X/m')
            ylabel(app.UIAxes, 'Y/m')
            zlabel(app.UIAxes, 'Z')
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '导入点云文件';
            app.Button_2 = uibutton(app.Panel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '浏览';
            app.EditField = uieditfield(app.Panel, 'text');
            app.Panel_2 = uipanel(app.UIFigure);
            app.Panel_2.Title = '初始化设置';
            app.EditField_3Label = uilabel(app.Panel_2);
            app.EditField_3Label.Text = '多边形边数';
            app.EditField_3 = uieditfield(app.Panel_2, 'numeric');
            app.EditField_3.Value = 4;
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '计算机拟合';
            app.Panel_3 = uipanel(app.UIFigure);
            app.Panel_3.Title = '输出结果';
            app.Label_2 = uilabel(app.Panel_3);
            app.Label_2.Text = '多边形顶点（米）';
            app.EditField_2 = uieditfield(app.Panel_3, 'text');
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '手动选点';
            app.RavenPanel = uipanel(app.UIFigure);
            app.RavenPanel.Title = 'Raven功能';
            app.Button_5 = uibutton(app.RavenPanel, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '绘制图像';
            app.Label = uilabel(app.RavenPanel);
            app.Label.Text = '房间高度(米)';
            app.EditField_4 = uieditfield(app.RavenPanel, 'numeric');
            app.EditField_4.Value = 1;
            app.Button_6 = uibutton(app.RavenPanel, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '导出文件';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = wallFind2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end